classdef OPEN_SR_QPI_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Group1                          matlab.ui.container.TabGroup
        WelcomeOpenSRQPITab             matlab.ui.container.Tab
        Logo_OIRL                       matlab.ui.control.Image
        ObliqueIlluminationDHMButton    matlab.ui.control.Button
        StructuredIlluminationDHMButton  matlab.ui.control.Button
        Label7                          matlab.ui.control.Label
        Label8                          matlab.ui.control.Label
        OIPreProcessingTab              matlab.ui.container.Tab
        ProcessHologram_OI              matlab.ui.control.Button
        LoadHologram_OI                 matlab.ui.control.Button
        Preview_OI                      matlab.ui.control.Button
        NextButton_OI                   matlab.ui.control.Button
        PreviousButton_OI               matlab.ui.control.Button
        LoadparametersforprocessingPanel  matlab.ui.container.Panel
        HintforselectingLabel           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        SelectLegendre_OI               matlab.ui.control.DropDown
        SelectthemaximumLegendrepolinomialforcompensationLabel  matlab.ui.control.Label
        Label1                          matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        Label3                          matlab.ui.control.Label
        RI_nim_OI                       matlab.ui.control.NumericEditField
        RefractiveindexforthemediumnimLabel  matlab.ui.control.Label
        RI_ns_OI                        matlab.ui.control.NumericEditField
        RefractiveindexforthesamplensubssubEditFieldLabel  matlab.ui.control.Label
        Region_OI                       matlab.ui.control.NumericEditField
        RegionforSpatialFilteringLabel  matlab.ui.control.Label
        Wavelength_OI                   matlab.ui.control.NumericEditField
        WavelengthumLabel               matlab.ui.control.Label
        PixelPitch_OI                   matlab.ui.control.NumericEditField
        PixelpitchumLabel               matlab.ui.control.Label
        Hologram_OI                     matlab.ui.control.UIAxes
        FT_OI                           matlab.ui.control.UIAxes
        SIPreProcessingTab              matlab.ui.container.Tab
        ParingofthehologramsPanel       matlab.ui.container.Panel
        Warning                         matlab.ui.control.Label
        ProcessHologram_SI              matlab.ui.control.Button
        LoadHologram_SI                 matlab.ui.control.Button
        Preview_SI                      matlab.ui.control.Button
        NextButton_SI                   matlab.ui.control.Button
        PreviousButton_SI               matlab.ui.control.Button
        LoadparametersforprocessingPanel_2  matlab.ui.container.Panel
        HintforselectingLabel_2         matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        SelectLegendre_SI               matlab.ui.control.DropDown
        SelectthemaximumLegendrepolinomialforcompensationDropDown_2Label  matlab.ui.control.Label
        Label4                          matlab.ui.control.Label
        label5                          matlab.ui.control.Label
        label6                          matlab.ui.control.Label
        RI_nim_SI                       matlab.ui.control.NumericEditField
        RefractiveindexforthemediumnsubimsubEditField_2Label  matlab.ui.control.Label
        RI_ns_SI                        matlab.ui.control.NumericEditField
        RefractiveindexforthesamplensubssubEditField_2Label  matlab.ui.control.Label
        Region_SI                       matlab.ui.control.NumericEditField
        RegionforSpatialFilteringLabel_2  matlab.ui.control.Label
        Wavelength_SI                   matlab.ui.control.NumericEditField
        WavelengthumEditField_2Label    matlab.ui.control.Label
        PixelPitch_SI                   matlab.ui.control.NumericEditField
        PixelpitchumEditField_2Label    matlab.ui.control.Label
        Hologram_SI                     matlab.ui.control.UIAxes
        FT_SI                           matlab.ui.control.UIAxes
        SRvisualizationTab              matlab.ui.container.Tab
        VisualizeResultsButton          matlab.ui.control.Button
        InformationofthevisualizationPanel  matlab.ui.container.Panel
        Image2                          matlab.ui.control.Image
        Information_compensation        matlab.ui.control.Label
        ThicknessCheckBox               matlab.ui.control.CheckBox
        SavingPanel                     matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        SaveThickCheckBox               matlab.ui.control.CheckBox
        SaveFTCheckBox                  matlab.ui.control.CheckBox
        SavePhaseCheckBox               matlab.ui.control.CheckBox
        SRFT                            matlab.ui.control.UIAxes
        SRPhaseMap                      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)

        % Helper function for pairing the holograms of Structured
        % Illumination
        function  buildPairingUI(app)
            s = app.UIFigure.UserData;

            % ----- guards -----
            if ~isstruct(s) || ~isfield(s,'ImagesCell') || isempty(s.ImagesCell)
                uialert(app.UIFigure,'Load holograms first.','No images');  return;
            end
            if ~isfield(s,'ImagesStack') || isempty(s.ImagesStack)
                uialert(app.UIFigure,'All holograms must have the same size to pair into N×M×2 matrices.','Size mismatch');
                return;
            end

            [H,W,N] = size(s.ImagesStack);

            if mod(N,2) ~= 0
                uialert(app.UIFigure, sprintf('You loaded %d images. Pairing needs an even number.', N), 'Odd count');
                return;
            end
            G = N/2;

            % remove old grid if present
            if isfield(s,'Pairing') && isfield(s.Pairing,'UI') && isfield(s.Pairing.UI,'grid')
                try delete(s.Pairing.UI.grid); end %#ok<TRYNC>
            end

            % make panel scroll if many rows
            app.ParingofthehologramsPanel.Scrollable = 'on';

            % grid inside your pairing panel
            gl = uigridlayout(app.ParingofthehologramsPanel, [N 2]);
            gl.RowHeight    = repmat({22},1,N);
            gl.ColumnWidth  = {'1x','fit'};
            gl.RowSpacing   = 4;  gl.ColumnSpacing = 6;  gl.Padding = [6 6 6 6];

            % ---- DROPDOWN items (replace your current items/data) ----
            items = ["Unassigned", compose("Group %d", 1:G)];   % labels
            data  = [0, 1:G];                                   % 0 means unassigned

            ddHandles = gobjects(N,1);
            lbHandles = gobjects(N,1);

            % ---- list each file + dropdown (replace the uidropdown(...) block) ----
            for i = 1:N
                [~,fname,ext] = fileparts(s.FilePaths(i));
                lbHandles(i) = uilabel(gl,'Text',sprintf('%d) %s%s',i,fname,ext), ...
                    'Interpreter','none');

                ddHandles(i) = uidropdown(gl, ...
                    'Items', cellstr(items), ...
                    'ItemsData', data, ...
                    'Value', 0, ...                 % <<< start UNASSIGNED
                    'UserData', i, ...
                    'ValueChangedFcn', @(src,ev) app.pairDropChanged(src,ev));
            end

            % initialize pairing state
            s.Pairing = struct;
            s.Pairing.numGroups   = G;
            s.Pairing.assign      = zeros(1,N);         % group per image (0=none)
            s.Pairing.slot        = zeros(1,N);         % 1 or 2 in that group
            s.Pairing.slotsFilled = zeros(G,1);         % count per group

            if isfloat(s.ImagesStack)
                s.Pairing.Groups = nan(H,W,2,G,'like',s.ImagesStack);
            else
                s.Pairing.Groups = zeros(H,W,2,G,'like',s.ImagesStack);
            end

            s.Pairing.UI.grid     = gl;
            s.Pairing.UI.labels   = lbHandles;
            s.Pairing.UI.dd       = ddHandles;

            app.UIFigure.UserData = s;

        end

        % Helper function for pairing the holograms of Structured
        % Illumination
        function pairDropChanged(app, src, event)
            s     = app.UIFigure.UserData;
            i     = src.UserData;          % which image row
            newG  = double(event.Value);   % 0 = Unassigned, else 1..G
            prevG = s.Pairing.assign(i);

            if newG == prevG, return; end

            % If user picked Unassigned, remove from previous group and exit
            if newG == 0
                if prevG > 0
                    slPrev = s.Pairing.slot(i);
                    if slPrev >= 1
                        H = size(s.Pairing.Groups,1);
                        W = size(s.Pairing.Groups,2);
                        emptySlice = zeros(H,W,'like',s.Pairing.Groups);
                        s.Pairing.Groups(:,:,slPrev,prevG) = emptySlice;
                        s.Pairing.slotsFilled(prevG) = s.Pairing.slotsFilled(prevG) - 1;
                    end
                end
                s.Pairing.assign(i) = 0; s.Pairing.slot(i) = 0;
                app.UIFigure.UserData = s;
                return
            end

            % target group full?
            if s.Pairing.slotsFilled(newG) >= 2
                uialert(app.UIFigure, sprintf('Group %d already has two images.', newG), 'Group full');
                % revert UI to previous selection (or Unassigned if none)
                if prevG > 0, src.Value = prevG; else, src.Value = 0; end
                return
            end

            % remove from previous group (if any)
            if prevG > 0
                slPrev = s.Pairing.slot(i);
                if slPrev >= 1
                    H = size(s.Pairing.Groups,1); W = size(s.Pairing.Groups,2);
                    emptySlice = zeros(H,W,'like',s.Pairing.Groups);
                    s.Pairing.Groups(:,:,slPrev,prevG) = emptySlice;
                    s.Pairing.slotsFilled(prevG) = s.Pairing.slotsFilled(prevG) - 1;
                end
                s.Pairing.assign(i) = 0; s.Pairing.slot(i) = 0;
            end

            % add to new group at next slot
            slNew = s.Pairing.slotsFilled(newG) + 1;
            s.Pairing.Groups(:,:,slNew,newG) = s.ImagesStack(:,:,i);
            s.Pairing.slotsFilled(newG) = slNew;
            s.Pairing.assign(i) = newG; s.Pairing.slot(i) = slNew;

            app.UIFigure.UserData = s;

            % --- popup when **all images have a group** (no zeros)
            if all(s.Pairing.assign > 0)
                % optional: ensure every used group has ≤2 (it should)
                msg = app.makePairingSummaryText(s);
                uialert(app.UIFigure, sprintf('All images assigned!\n\n%s', msg), ...
                    'Pairing complete','Icon','info');
            end
        end

        % Helper function for pairing the holograms of Structured
        % Illumination
        function msg = makePairingSummaryText(~, s)
            % Returns a multi-line summary "Group g: name1, name2"
            G = s.Pairing.numGroups;
            lines = strings(G,1);

            for g = 1:G
                idx = find(s.Pairing.assign == g);          % images in this group

                % order by slot (1 then 2)
                [~,ord] = sort(s.Pairing.slot(idx));
                idx = idx(ord);

                if isempty(idx)
                    lines(g) = sprintf('Group %d: (empty)', g);
                else
                    names = strings(1, numel(idx));
                    for k = 1:numel(idx)
                        [~,fn,ex] = fileparts(s.FilePaths(idx(k)));
                        names(k) = fn + ex;
                    end
                    lines(g) = sprintf('Group %d: %s', g, strjoin(cellstr(names), ', '));
                end
            end

            msg = strjoin(cellstr(lines), newline);
        end

        % Helper function for visualizing the demudulation of Structured
        % Illumination
        function repeat = askRepeatWithPreview(~,img, prompt, varargin)
            
            % ---- Parse inputs
            ip = inputParser;
            addParameter(ip, 'Parent', [], @(h) isempty(h) || isgraphics(h));
            addParameter(ip, 'Title', 'Minimization output', @ischar);
            addParameter(ip, 'ImageFontSize', 14, @isnumeric);
            addParameter(ip, 'PromptFontSize', 14, @isnumeric);
            addParameter(ip, 'ButtonFontSize', 14, @isnumeric);
            addParameter(ip, 'ShowAxes', false, @islogical);
            addParameter(ip, 'ShowTitle', false, @islogical);
            parse(ip, varargin{:});
            P = ip.Results;

            % ---- UI figure
            if isempty(P.Parent) || ~isvalid(P.Parent)
                fig = uifigure('Name','Review compensation','WindowStyle','modal', ...
                    'Position',[100 100 760 560]);
            else
                fig = uifigure('Name','Review compensation','WindowStyle','modal', ...
                    'Position',[P.Parent.Position(1)+60, P.Parent.Position(2)+60, 760, 560]);
            end

            gl = uigridlayout(fig,[2 3]);
            gl.RowHeight    = {'1x', 64};
            gl.ColumnWidth  = {'1x', 120, 120};
            gl.Padding      = [12 12 12 12];
            gl.RowSpacing   = 10;
            gl.ColumnSpacing= 10;

            % ---- Image
            ax = uiaxes(gl);
            ax.Layout.Row = 1; ax.Layout.Column = [1 3];
            imagesc(ax, img);
            axis(ax, 'image');
            colormap(ax, gray);

            % Hide axes decorations if requested
            if ~P.ShowAxes
                ax.XTick = []; ax.YTick = [];
                ax.XColor = 'none'; ax.YColor = 'none';
                ax.Box = 'off';
                ax.Toolbar.Visible = 'off';
            end

            % Title (optional)
            if P.ShowTitle
                t = title(ax, P.Title);
                t.FontSize = P.ImageFontSize;
            else
                title(ax, '');
            end

            % Colorbar (keep, but larger font; remove label)
            % cb = colorbar(ax);
            % cb.FontSize = P.ImageFontSize;
            % cb.Label.String = '';  % no label

            % ---- Prompt + buttons
            lbl = uilabel(gl, 'Text', prompt, 'FontWeight','bold', ...
                'HorizontalAlignment','center', 'FontSize', P.PromptFontSize);
            lbl.Layout.Row = 2; lbl.Layout.Column = 1;

            btnAccept = uibutton(gl, 'Text','NO',  'FontWeight','bold', 'FontSize', P.ButtonFontSize);
            btnRepeat = uibutton(gl, 'Text','YES', 'FontWeight','bold', 'FontSize', P.ButtonFontSize);
            btnAccept.Layout.Row = 2; btnAccept.Layout.Column = 2;
            btnRepeat.Layout.Row = 2; btnRepeat.Layout.Column = 3;

            % Default answer = false (NO = accept)
            setappdata(fig,'answer',false);
            btnAccept.ButtonPushedFcn = @(~,~) done(false);
            btnRepeat.ButtonPushedFcn = @(~,~) done(true);
            fig.CloseRequestFcn       = @(~,~) done(false);

            drawnow; uiwait(fig);
            repeat = getappdata(fig,'answer');
            if isvalid(fig), delete(fig); end

            function done(val)
                if isvalid(fig)
                    setappdata(fig,'answer',val);
                    uiresume(fig);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Start on Welcome
            app.Group1.SelectedTab = app.WelcomeOpenSRQPITab;
            app.OIPreProcessingTab.Parent = [];
            app.SIPreProcessingTab.Parent = [];
            app.SRvisualizationTab.Parent = [];
        end

        % Button pushed function: LoadHologram_OI
        function LoadHologram_OIPushed(app, event)
            % 1) Let user pick multiple images from a single folder
            [files, path] = uigetfile( ...
                {'*.tif;*.tiff;*.png;*.jpg;*.jpeg;*.bmp','Image files'; '*.*','All files'}, ...
                'Select images (same folder)', ...
                'MultiSelect','on');

            if isequal(files,0)
                return;   % user canceled
            end
            if ischar(files), files = {files}; end

            % 2) Reset storage (overwrite previous)
            ud = struct;
            ud.FilePaths   = string(fullfile(path, files));  % full paths
            ud.ImagesCell  = {};
            ud.ImagesStack = [];

            % 3) Read images (convert RGB->gray; remove this if you want color)
            for k = 1:numel(ud.FilePaths)
                I = imread(ud.FilePaths(k));
                if ndims(I) == 3 && size(I,3) == 3
                    I = rgb2gray(I);
                end
                ud.ImagesCell{k} = double(I);
            end

            % 4) If all same size, build a numeric stack H×W×N
            if ~isempty(ud.ImagesCell)
                szs = cellfun(@size, ud.ImagesCell, 'UniformOutput', false);
                if all(cellfun(@(s) isequal(s, szs{1}), szs))
                    S = szs{1}; N = numel(ud.ImagesCell);
                    stack = zeros(S(1), S(2), N, 'like', ud.ImagesCell{1});
                    for k = 1:N
                        stack(:,:,k) = ud.ImagesCell{k}; 
                    end
                    ud.ImagesStack = stack;
                end
            end

            % 5) Store in app memory
            app.UIFigure.UserData = ud;

            % 6) Quick summary
            uialert(app.UIFigure, ...
                sprintf('Loaded %d image(s)', numel(ud.FilePaths)), ...
                'Loaded','Icon','info');
            disp('images loaded')
        end

        % Button pushed function: Preview_OI
        function Preview_OIPushed(app, event)

            s = app.UIFigure.UserData;
            if ~isstruct(s) || ~isfield(s,'ImagesCell') || isempty(s.ImagesCell)
                uialert(app.UIFigure,'Please load holograms first.','No images');
                return
            end

            s.Index = 1;                         % start at first
            I = s.ImagesCell{s.Index};

            ax = app.Hologram_OI;
            cla(ax);
            imshow(I, [], 'Parent', ax);         % [] = autoscale
            colormap(ax, gray); axis(ax,'image');
            title(ax, sprintf('Hologram %d / %d', s.Index, numel(s.ImagesCell)));
            cb = colorbar(ax);              % attach to this UIAxes
            cl = ax.CLim;                   % autoscaled limits from imshow([], ...)
            cb.Ticks      = cl;             % only min & max
            cb.TickLabels = compose('%.3g', cl);
            t = cb.Label; t.String='Amplitude (a.u.)'; 
            t.FontSize=11;
            pos=t.Position; pos(1)=1.2;  t.Position=pos;
       
         
            F  = fftshift(fft2(I));
            PS = log1p(abs(F));                 % log-magnitude spectrum
            ax2 = app.FT_OI;                  % right axes
            cla(ax2);
            imshow(PS, [], 'Parent', ax2);
            colormap(ax2, gray); axis(ax2,'image');
            title(ax2, 'Fourier Transform');
            cb2  = colorbar(ax2);              % attach to this UIAxes
            cl2  = ax2.CLim;                   % autoscaled limits from imshow([], ...)
            cb2 .Ticks      = cl2;             % only min & max
            cb2 .TickLabels = compose('%.3g', cl2);
            t = cb2 .Label; t.String='Amplitude (a.u.)';
            t.FontSize=11;
            pos=t.Position; pos(1)=1.2;  t.Position=pos;


            app.UIFigure.UserData = s;

            state = app.UIFigure.UserData;
            if isstruct(state) && isfield(state,'ImagesCell') && ~isempty(state.ImagesCell)
                app.Hologram_OI.Visible         = 'on';
                app.FT_OI.Visible         = 'on';
                app.PreviousButton_OI.Visible = 'on';
                app.NextButton_OI.Visible     = 'on';
                % app.HologramLabel.Visible  = 'on';   % if you have a label
            end
        end

        % Button pushed function: NextButton_OI
        function NextButton_OIPushed(app, event)

            s = app.UIFigure.UserData;
            if ~isstruct(s) || ~isfield(s,'ImagesCell') || isempty(s.ImagesCell)
                uialert(app.UIFigure,'Please load holograms first.','No images');
                return
            end

            N = numel(s.ImagesCell);
            if ~isfield(s,'Index') || ~isscalar(s.Index) || isempty(s.Index)
                s.Index = 1;
            end
            s.Index = mod(s.Index, N) + 1;       % 1->2->...->N->1

         
            I = s.ImagesCell{s.Index};
            ax = app.Hologram_OI;
            cla(ax);
            imshow(I, [], 'Parent', ax);
            colormap(ax, gray); axis(ax,'image');
            title(ax, sprintf('Hologram %d / %d', s.Index, N));
            cb = colorbar(ax);              % attach to this UIAxes
            cl = ax.CLim;                   % autoscaled limits from imshow([], ...)
            cb.Ticks      = cl;             % only min & max
            cb.TickLabels = compose('%.3g', cl);
            t = cb.Label; t.String='Amplitude (a.u.)'; 
            t.FontSize=11;
            pos=t.Position; pos(1)=1.2;  t.Position=pos;

         
            F  = fftshift(fft2(I));
            PS = log1p(abs(F));
            ax2 = app.FT_OI;
            cla(ax2);
            imshow(PS, [], 'Parent', ax2);
            colormap(ax2, gray); axis(ax2,'image');
            title(ax2, 'Fourier Transform');
            cb2  = colorbar(ax2);              % attach to this UIAxes
            cl2  = ax2.CLim;                   % autoscaled limits from imshow([], ...)
            cb2 .Ticks      = cl2;             % only min & max
            cb2 .TickLabels = compose('%.3g', cl2);
            t = cb2 .Label; t.String='Amplitude (a.u.)';
            t.FontSize=11;
            pos=t.Position; pos(1)=1.2;  t.Position=pos;

            app.UIFigure.UserData = s;
        end

        % Button pushed function: PreviousButton_OI
        function PreviousButton_OIPushed(app, event)

            s = app.UIFigure.UserData;
            if ~isstruct(s) || ~isfield(s,'ImagesCell') || isempty(s.ImagesCell)
                uialert(app.UIFigure,'Please load holograms first.','No images');
                return
            end

            N = numel(s.ImagesCell);
            if ~isfield(s,'Index') || ~isscalar(s.Index) || isempty(s.Index)
                s.Index = 1;
            end
            s.Index = mod(s.Index-2, N) + 1;     % 1->N, 2->1, etc.

         
            I = s.ImagesCell{s.Index};
            ax = app.Hologram_OI;
            cla(ax);
            imshow(I, [], 'Parent', ax);
            colormap(ax, gray); axis(ax,'image');
            title(ax, sprintf('Hologram %d / %d', s.Index, N));
            cb = colorbar(ax);              % attach to this UIAxes
            cl = ax.CLim;                   % autoscaled limits from imshow([], ...)
            cb.Ticks      = cl;             % only min & max
            cb.TickLabels = compose('%.3g', cl);
            t = cb.Label; t.String='Amplitude (a.u.)'; 
            t.FontSize=11;
            pos=t.Position; pos(1)=1.2;  t.Position=pos;

           
            F  = fftshift(fft2(I));
            PS = log1p(abs(F));
            ax2 = app.FT_OI;
            cla(ax2);
            imshow(PS, [], 'Parent', ax2);
            colormap(ax2, gray); axis(ax2,'image');
            title(ax2, 'Fourier Transform');
            cb2  = colorbar(ax2);              % attach to this UIAxes
            cl2  = ax2.CLim;                   % autoscaled limits from imshow([], ...)
            cb2 .Ticks      = cl2;             % only min & max
            cb2 .TickLabels = compose('%.3g', cl2);
            t = cb2 .Label; t.String='Amplitude (a.u.)';
            t.FontSize=11;
            pos=t.Position; pos(1)=1.2;  t.Position=pos;
            app.UIFigure.UserData = s;
        end

        % Value changed function: PixelPitch_OI
        function PixelPitch_OIValueChanged(app, event)
            s = app.UIFigure.UserData;  if ~isstruct(s), s = struct; end
            if ~isfield(s,'Params'), s.Params = struct; end
            v = app.PixelPitch_OI.Value;   % NumericEditField => double
            if ~isnumeric(v), v = str2double(v); end
            s.Params.px_um = v;
            app.UIFigure.UserData = s;
        end

        % Value changed function: Wavelength_OI
        function Wavelength_OIValueChanged(app, event)
            s = app.UIFigure.UserData;  if ~isstruct(s), s = struct; end
            if ~isfield(s,'Params'), s.Params = struct; end
            v = app.Wavelength_OI.Value;
            if ~isnumeric(v), v = str2double(v); end
            s.Params.lambda_um = v;
            app.UIFigure.UserData = s;
        end

        % Value changed function: Region_OI
        function Region_OIValueChanged(app, event)
            s = app.UIFigure.UserData;  if ~isstruct(s), s = struct; end
            if ~isfield(s,'Params'), s.Params = struct; end
            v = app.Region_OI.Value;
            if ~isnumeric(v), v = str2double(v); end
            s.Params.sf_region = v;
            app.UIFigure.UserData = s;
        end

        % Value changed function: RI_ns_OI
        function RI_ns_OIValueChanged(app, event)
            s = app.UIFigure.UserData;  if ~isstruct(s), s = struct; end
            if ~isfield(s,'Params'), s.Params = struct; end
            v = app.RI_ns_OI.Value;
            if ~isnumeric(v), v = str2double(v); end
            s.Params.ns = v;
            app.UIFigure.UserData = s;
        end

        % Value changed function: RI_nim_OI
        function RI_nim_OIValueChanged(app, event)
            s = app.UIFigure.UserData;  if ~isstruct(s), s = struct; end
            if ~isfield(s,'Params'), s.Params = struct; end
            v = app.RI_nim_OI.Value;
            if ~isnumeric(v), v = str2double(v); end
            s.Params.nm = v;
            app.UIFigure.UserData = s;
        end

        % Value changed function: SelectLegendre_OI
        function SelectLegendre_OIValueChanged(app, event)
            % Get shared state
            s = app.UIFigure.UserData;
            if ~isstruct(s), s = struct; end
            if ~isfield(s,'Params'), s.Params = struct; end

            % NEW value from the dropdown (label like "L3")
            lbl = string(event.Value);                      % <- use event.Value (current)
            tok = regexp(lbl, 'L(\d+)', 'tokens', 'once');  % extract number

            if ~isempty(tok)
                s.Params.Lmax = str2double(tok{1});         % numeric 1..N
            else
                s.Params.Lmax = NaN;
            end
            s.Params.LmaxLabel = lbl;                       % keep label too

            % Save back and echo
            app.UIFigure.UserData = s;            
        end

        % Button pushed function: LoadHologram_SI
        function LoadHologram_SIButtonPushed(app, event)
            % 1) Let user pick multiple images from a single folder
            [files, path] = uigetfile( ...
                {'*.tif;*.tiff;*.png;*.jpg;*.jpeg;*.bmp','Image files'; '*.*','All files'}, ...
                'Select images (same folder)', ...
                'MultiSelect','on');

            if isequal(files,0)
                return;   % user canceled
            end
            if ischar(files), files = {files}; end

            % 2) Reset storage (overwrite previous)
            ud = struct;
            ud.FilePaths   = string(fullfile(path, files));  % full paths
            ud.ImagesCell  = {};
            ud.ImagesStack = [];

            % 3) Read images (convert RGB->gray; remove this if you want color)
            for k = 1:numel(ud.FilePaths)
                I = imread(ud.FilePaths(k));
                if ndims(I) == 3 && size(I,3) == 3
                    I = rgb2gray(I);
                end
                ud.ImagesCell{k} = double(I);
            end

            % 4) If all same size, build a numeric stack H×W×N
            if ~isempty(ud.ImagesCell)
                szs = cellfun(@size, ud.ImagesCell, 'UniformOutput', false);
                if all(cellfun(@(s) isequal(s, szs{1}), szs))
                    S = szs{1}; N = numel(ud.ImagesCell);
                    stack = zeros(S(1), S(2), N, 'like', ud.ImagesCell{1});
                    for k = 1:N
                        stack(:,:,k) = ud.ImagesCell{k}; 
                    end
                    ud.ImagesStack = stack;
                end
            end

            % 5) Store in app memory
            app.UIFigure.UserData = ud;

            % 6) Quick summary
            uialert(app.UIFigure, ...
                sprintf('Loaded %d image(s)', numel(ud.FilePaths)), ...
                'Loaded','Icon','info');
            % after you finish loading and storing s.ImagesCell / s.ImagesStack
            app.buildPairingUI();   % <- add this line
            disp('images loaded')
        end

        % Button pushed function: Preview_SI
        function Preview_SIButtonPushed(app, event)
            
            s = app.UIFigure.UserData;
            if ~isstruct(s) || ~isfield(s,'ImagesCell') || isempty(s.ImagesCell)
                uialert(app.UIFigure,'Please load holograms first.','No images');
                return
            end

            s.Index = 1;                         % start at first
            I = s.ImagesCell{s.Index};

            ax = app.Hologram_SI;
            cla(ax);
            imshow(I, [], 'Parent', ax);         % [] = autoscale
            colormap(ax, gray); axis(ax,'image');
            title(ax, sprintf('Hologram %d / %d', s.Index, numel(s.ImagesCell)));
            cb = colorbar(ax);              % attach to this UIAxes
            cl = ax.CLim;                   % autoscaled limits from imshow([], ...)
            cb.Ticks      = cl;             % only min & max
            cb.TickLabels = compose('%.3g', cl);
            t = cb.Label; t.String='Amplitude (a.u.)'; 
            t.FontSize=11;
            pos=t.Position; pos(1)=1.2;  t.Position=pos;
       

            F  = fftshift(fft2(I));
            PS = log1p(abs(F));                 % log-magnitude spectrum
            ax2 = app.FT_SI;                  % right axes
            cla(ax2);
            imshow(PS, [], 'Parent', ax2);
            colormap(ax2, gray); axis(ax2,'image');
            title(ax2, 'Fourier Transform');
            cb2  = colorbar(ax2);              % attach to this UIAxes
            cl2  = ax2.CLim;                   % autoscaled limits from imshow([], ...)
            cb2 .Ticks      = cl2;             % only min & max
            cb2 .TickLabels = compose('%.3g', cl2);
            t = cb2 .Label; t.String='Amplitude (a.u.)';
            t.FontSize=11;
            pos=t.Position; pos(1)=1.2;  t.Position=pos;

            app.UIFigure.UserData = s;

            state = app.UIFigure.UserData;
            if isstruct(state) && isfield(state,'ImagesCell') && ~isempty(state.ImagesCell)
                app.Hologram_SI.Visible         = 'on';
                app.FT_SI.Visible         = 'on';
                app.PreviousButton_SI.Visible = 'on';
                app.NextButton_SI.Visible     = 'on';
                % app.HologramLabel.Visible  = 'on';   % if you have a label
            end
        end

        % Button pushed function: PreviousButton_SI
        function PreviousButton_SIPushed(app, event)
            s = app.UIFigure.UserData;
            if ~isstruct(s) || ~isfield(s,'ImagesCell') || isempty(s.ImagesCell)
                uialert(app.UIFigure,'Please load holograms first.','No images');
                return
            end

            N = numel(s.ImagesCell);
            if ~isfield(s,'Index') || ~isscalar(s.Index) || isempty(s.Index)
                s.Index = 1;
            end
            s.Index = mod(s.Index-2, N) + 1;     % 1->N, 2->1, etc.

            I = s.ImagesCell{s.Index};
            ax = app.Hologram_SI;
            cla(ax);
            imshow(I, [], 'Parent', ax);
            colormap(ax, gray); axis(ax,'image');
            title(ax, sprintf('Hologram %d / %d', s.Index, N));
            cb = colorbar(ax);              % attach to this UIAxes
            cl = ax.CLim;                   % autoscaled limits from imshow([], ...)
            cb.Ticks      = cl;             % only min & max
            cb.TickLabels = compose('%.3g', cl);
            t = cb.Label; t.String='Amplitude (a.u.)'; 
            t.FontSize=11;
            pos=t.Position; pos(1)=1.2;  t.Position=pos;
       
            F  = fftshift(fft2(I));
            PS = log1p(abs(F));
            ax2 = app.FT_SI;
            cla(ax2);
            imshow(PS, [], 'Parent', ax2);
            colormap(ax2, gray); axis(ax2,'image');
            title(ax2, 'Fourier Transform');
            cb2  = colorbar(ax2);              % attach to this UIAxes
            cl2  = ax2.CLim;                   % autoscaled limits from imshow([], ...)
            cb2 .Ticks      = cl2;             % only min & max
            cb2 .TickLabels = compose('%.3g', cl2);
            t = cb2 .Label; t.String='Amplitude (a.u.)';
            t.FontSize=11;
            pos=t.Position; pos(1)=1.2;  t.Position=pos;

            app.UIFigure.UserData = s;
        end

        % Button pushed function: NextButton_SI
        function NextButton_SIPushed(app, event)
            s = app.UIFigure.UserData;
            if ~isstruct(s) || ~isfield(s,'ImagesCell') || isempty(s.ImagesCell)
                uialert(app.UIFigure,'Please load holograms first.','No images');
                return
            end

            N = numel(s.ImagesCell);
            if ~isfield(s,'Index') || ~isscalar(s.Index) || isempty(s.Index)
                s.Index = 1;
            end
            s.Index = mod(s.Index, N) + 1;       % 1->2->...->N->1

            I = s.ImagesCell{s.Index};
            ax = app.Hologram_SI;
            cla(ax);
            imshow(I, [], 'Parent', ax);
            colormap(ax, gray); axis(ax,'image');
            title(ax, sprintf('Hologram %d / %d', s.Index, N));
            cb = colorbar(ax);              % attach to this UIAxes
            cl = ax.CLim;                   % autoscaled limits from imshow([], ...)
            cb.Ticks      = cl;             % only min & max
            cb.TickLabels = compose('%.3g', cl);
            t = cb.Label; t.String='Amplitude (a.u.)'; 
            t.FontSize=11;
            pos=t.Position; pos(1)=1.2;  t.Position=pos;
       
            F  = fftshift(fft2(I));
            PS = log1p(abs(F));
            ax2 = app.FT_SI;
            cla(ax2);
            imshow(PS, [], 'Parent', ax2);
            colormap(ax2, gray); axis(ax2,'image');
            title(ax2, 'Fourier Transform');
            cb2  = colorbar(ax2);              % attach to this UIAxes
            cl2  = ax2.CLim;                   % autoscaled limits from imshow([], ...)
            cb2 .Ticks      = cl2;             % only min & max
            cb2 .TickLabels = compose('%.3g', cl2);
            t = cb2 .Label; t.String='Amplitude (a.u.)';
            t.FontSize=11;
            pos=t.Position; pos(1)=1.2;  t.Position=pos;

            app.UIFigure.UserData = s;
        end

        % Value changed function: PixelPitch_SI
        function PixelPitch_SIValueChanged(app, event)
            s = app.UIFigure.UserData;  if ~isstruct(s), s = struct; end
            if ~isfield(s,'Params'), s.Params = struct; end
            v = app.PixelPitch_SI.Value;   % NumericEditField => double
            if ~isnumeric(v), v = str2double(v); end
            s.Params.px_um = v;
            app.UIFigure.UserData = s;  
        end

        % Value changed function: Wavelength_SI
        function Wavelength_SIValueChanged(app, event)
            s = app.UIFigure.UserData;  if ~isstruct(s), s = struct; end
            if ~isfield(s,'Params'), s.Params = struct; end
            v = app.Wavelength_SI.Value;
            if ~isnumeric(v), v = str2double(v); end
            s.Params.lambda_um = v;
            app.UIFigure.UserData = s;
        end

        % Value changed function: Region_SI
        function Region_SIValueChanged(app, event)
            s = app.UIFigure.UserData;  if ~isstruct(s), s = struct; end
            if ~isfield(s,'Params'), s.Params = struct; end
            v = app.Region_SI.Value;
            if ~isnumeric(v), v = str2double(v); end
            s.Params.sf_region = v;
            app.UIFigure.UserData = s;
        end

        % Value changed function: RI_ns_SI
        function RI_ns_SIValueChanged(app, event)
            s = app.UIFigure.UserData;  if ~isstruct(s), s = struct; end
            if ~isfield(s,'Params'), s.Params = struct; end
            v = app.RI_ns_SI.Value;
            if ~isnumeric(v), v = str2double(v); end
            s.Params.ns = v;
            app.UIFigure.UserData = s; 
        end

        % Value changed function: RI_nim_SI
        function RI_nim_SIValueChanged(app, event)
            s = app.UIFigure.UserData;  if ~isstruct(s), s = struct; end
            if ~isfield(s,'Params'), s.Params = struct; end
            v = app.RI_nim_SI.Value;
            if ~isnumeric(v), v = str2double(v); end
            s.Params.nm = v;
            app.UIFigure.UserData = s;
        end

        % Value changed function: SelectLegendre_SI
        function SelectLegendre_SIValueChanged(app, event)
            % Get shared state
            s = app.UIFigure.UserData;
            if ~isstruct(s), s = struct; end
            if ~isfield(s,'Params'), s.Params = struct; end

            % NEW value from the dropdown (label like "L3")
            lbl = string(event.Value);                      % <- use event.Value (current)
            tok = regexp(lbl, 'L(\d+)', 'tokens', 'once');  % extract number

            if ~isempty(tok)
                s.Params.Lmax = str2double(tok{1});         % numeric 1..N
            else
                s.Params.Lmax = NaN;
            end
            s.Params.LmaxLabel = lbl;                       % keep label too

            % Save back and echo
            app.UIFigure.UserData = s;
        end

        % Button pushed function: StructuredIlluminationDHMButton
        function StructuredIlluminationDHMButtonPushed(app, event)
            app.SIPreProcessingTab.Parent = app.Group1;
            app.WelcomeOpenSRQPITab.Parent = [];
            app.OIPreProcessingTab.Parent = [];
            app.SRvisualizationTab.Parent = [];
            
        end

        % Button pushed function: ObliqueIlluminationDHMButton
        function ObliqueIlluminationDHMButtonPushed(app, event)
            app.OIPreProcessingTab.Parent = app.Group1;
            app.WelcomeOpenSRQPITab.Parent = [];
            app.SIPreProcessingTab.Parent = [];
            app.SRvisualizationTab.Parent = [];
          
        end

        % Button pushed function: ProcessHologram_OI
        function ProcessHologram_OIPushed(app, event)
             s = app.UIFigure.UserData; 
           
            [~,TotalHolos] = size(s.ImagesCell);
            [Row, Col] = size(s.ImagesCell{1});
            SumNormFT = zeros(Row,Col);
            CircSum = zeros(Row,Col);
            region = s.Params.sf_region;
            lambda = s.Params.lambda_um; 
            dxy = s.Params.px_um;
            max_order = s.Params.Lmax;
            k = 2*pi/lambda;

            for num = 1:TotalHolos
                [h1,M,N,m,n] = Compensation_SR.holo_read(s.ImagesCell{num});
                [~,HOLO_FT1,circmask1] = Compensation_SR.spatialFilter(h1,M,N, region);
                PS_demod1 = log(abs((HOLO_FT1).^2));
                compensated_holos = Compensation_SR.Vortex_for_SR(HOLO_FT1, M,N,k, m,n, lambda, dxy, max_order);
                PS_compensation1 = log(abs((FT(compensated_holos)).^2));
                %Centering of binary mask for weighting
                % inpu: power spectrum log(abs(FT(holo)).^2))
                % I1 : spectrum BEFORE centering   (real or complex)
                % I2 : spectrum AFTER  centering   (real or complex)
                % BW : binary mask made from I1
                % BW_shifted : BW moved so it matches I2
                compensated_Circ_mask = Compensation_SR.shiftMaskByMax(PS_demod1, PS_compensation1, circmask1);
                normalized_FT = Compensation_SR.normalization_FT(FT(compensated_holos)); 
                SumNormFT = SumNormFT + normalized_FT;
                CircSum = CircSum + compensated_Circ_mask;
            end
       
            normalization_weights = 1 ./ CircSum; % Inverse mask for weighting
            normalization_weights(isinf(normalization_weights)) = 0;
           
            NormalizedSRFT = SumNormFT .*normalization_weights;
            NormalizedSRComplexField = IFT(NormalizedSRFT);

            s = app.UIFigure.UserData;   if ~isstruct(s), s = struct; end
            if ~isfield(s,'Results'), s.Results = struct; end
            if ~isfield(s.Results,'OI'), s.Results.OI = struct; end

            s.Results.OI.NormalizedSRComplexField = NormalizedSRComplexField;
            s.ModeProcessing = 1; 

            s.Params.normalization_weights = normalization_weights;
            s.Params.compensated_Circ_mask = compensated_Circ_mask;


            app.UIFigure.UserData = s;

            % --- notify user when processing finishes ---
            uialert(app.UIFigure, ...
                'Now you can proceed to the visualization tab.', ...
                'Processing complete', 'Icon','info');

            app.SRvisualizationTab.Parent = app.Group1;

        end

        % Button pushed function: ProcessHologram_SI
        function ProcessHologram_SIButtonPushed(app, event)

            s = app.UIFigure.UserData;
            % number of groups:
            G = size(s.Pairing.Groups, 4);
            [Row,Col] = size(s.Pairing.Groups(:,:,1,1));   % first image in group 1 
            SumNormFT = zeros(Row,Col);
            CircSum = zeros(Row,Col);
            region = s.Params.sf_region;
            lambda = s.Params.lambda_um; 
            dxy = s.Params.px_um;
            max_order = s.Params.Lmax;
            k = 2*pi/lambda;
            for Group = 1:G
                name1 =  s.Pairing.Groups(:,:,1,Group);
                name2 =  s.Pairing.Groups(:,:,2,Group);

                % Read holograms
                [h1,~,~,~,~] = Compensation_SR.holo_read(name1);
                [h2,M,N,m,n] = Compensation_SR.holo_read(name2);

                % Spatial filter need the raw holo (complex field)
                [~,HOLO_FT1,circmask1] = Compensation_SR.spatialFilter(h1,M,N, region);
                [~,HOLO_FT2,circmask2] = Compensation_SR.spatialFilter(h2,M,N, region);

                % Input: FT of the filtered holo
                [fx_max, fy_max] = Compensation_SR.findMax(HOLO_FT1, M,N);

                % Asignation for next functions of the FT of the filtered hologram
                holo_FT(:,:,1) = HOLO_FT1;
                holo_FT(:,:,2) = HOLO_FT2;

                % Demodulation Gplus Output:FT of demodulation
                askFn = @(img,prompt) app.askRepeatWithPreview( ...
                    img, prompt, ...
                    'Parent',        app.UIFigure, ...
                    'ImageFontSize', 16, ...
                    'PromptFontSize',16, ...
                    'ButtonFontSize',16, ...
                    'ShowAxes',      false, ...
                    'ShowTitle',     false);
                Gplus_demod = Compensation_SR.minimaztion_heuristic(holo_FT, fx_max, fy_max, askFn);
                PS_demod1 = log(abs((Gplus_demod).^2));
                % figure, imagesc(log(abs((Gplus_demod).^2))), title 'output demodulation'

                %Compensation with Vortex
                % Input: filtered FT of the hologram
                gplus1 = Compensation_SR.Vortex_for_SR(Gplus_demod, M,N,k, m,n, lambda, dxy, max_order);
                PS_compensation1 = log(abs((FT(gplus1)).^2));

                % Demodulation Gminus
                askFn = @(img,prompt) app.askRepeatWithPreview( ...
                    img, prompt, ...
                    'Parent',        app.UIFigure, ...
                    'ImageFontSize', 16, ...
                    'PromptFontSize',16, ...
                    'ButtonFontSize',16, ...
                    'ShowAxes',      false, ...
                    'ShowTitle',     false);
                Gminus_demod = Compensation_SR.minimaztion_heuriticII(holo_FT, fx_max, fy_max, askFn);
                PS_demod2 = log(abs((Gminus_demod).^2));

                %Compensation with Vortex
                % Input: filtered FT of the hologram
                gminus1 = Compensation_SR.Vortex_for_SR(Gminus_demod, M,N,k, m,n, lambda, dxy,max_order);
                PS_compensation2 = log(abs((FT(gminus1)).^2));

                Ft_norm1 = Compensation_SR.normalization_FT(FT(gplus1));
                Ft_norm2 = Compensation_SR.normalization_FT(FT(gminus1));

                %Centering of binary mask for weighting
                % inpu: power spectrum log(abs(FT(holo)).^2))
                % I1 : spectrum BEFORE centering   (real or complex)
                % I2 : spectrum AFTER  centering   (real or complex)
                % BW : binary mask made from I1
                % BW_shifted : BW moved so it matches I2
                [CricMaskCent1] = Compensation_SR.shiftMaskByMax(PS_demod1, PS_compensation1, circmask1);
                [CricMaskCent2] = Compensation_SR.shiftMaskByMax(PS_demod2, PS_compensation2, circmask2);

                SumNormFT = SumNormFT + Ft_norm1 + Ft_norm2;
                CircSum = CircSum + CricMaskCent1 + CricMaskCent2;

            end

            normalization_weights = 1 ./ CircSum; % Inverse mask for weighting
            normalization_weights(isinf(normalization_weights)) = 0;  
            NormalizedSRFT = SumNormFT .*normalization_weights;
            NormalizedSRComplexField = IFT(NormalizedSRFT);
            
            s = app.UIFigure.UserData;   if ~isstruct(s), s = struct; end
            if ~isfield(s,'Results'), s.Results = struct; end
            if ~isfield(s.Results,'OI'), s.Results.SI = struct; end

            s.Results.SI.NormalizedSRComplexField = NormalizedSRComplexField;
            s.ModeProcessing = 2;
            s.Params.normalization_weights = normalization_weights;
            s.Params.compensated_Circ_mask = CricMaskCent1;

            app.UIFigure.UserData = s;

            % --- notify user when processing finishes ---
            uialert(app.UIFigure, ...
                'Now you can proceed to the visualization tab.', ...
                'Processing complete', 'Icon','info');

            app.SRvisualizationTab.Parent = app.Group1;

        end

        % Button pushed function: VisualizeResultsButton
        function VisualizeResultsButtonPushed(app, event)

            s = app.UIFigure.UserData;

            variable = s.ModeProcessing;
        
            % Selecting the correct result.
            if  variable == 1 
                ComplexField = s.Results.OI.NormalizedSRComplexField;
            else 
                ComplexField = s.Results.SI.NormalizedSRComplexField;
            end

            % -------- Phase map (left axes) --------
            axL = app.SRPhaseMap;
            cla(axL);
            phaseMap = angle(ComplexField);
            imshow(phaseMap, [], 'Parent', axL, 'InitialMagnification','fit');
            axis(axL,'image'); colormap(axL, gray(256));
            title(axL,'Phase map');
            % single colorbar: only min/max + label
            cbL = colorbar(axL,'Location','eastoutside');   % returns existing one if present
            cl  = axL.CLim;                                 % [min max] used for display
            cbL.Ticks      = [cl(1) cl(2)];
            cbL.TickLabels = {sprintf('%.1f',cl(1)), sprintf('%.1f',cl(2))};
            cbL.Label.String     = 'Phase (rad)';
            % cbL.Label.Position = [1.2, 0.03, 0]; 
            cbL.Label.FontSize   = 12;         % label size
            cbL.FontSize         = 10;         % tick size
            t = cbL.Label;
            pos = t.Position; pos(1) = 1.2; t.Position = pos;
           
            % -------- FT magnitude (right axes) --------
            axR = app.SRFT;
            cla(axR);
            F     = FT(ComplexField);
            FTmag = (abs(F).^.1);                           % or your own FT display
            imshow(FTmag, [], 'Parent', axR, 'InitialMagnification','fit');
            axis(axR,'image'); colormap(axR, gray);
            title(axR,'FT magnitude (log)');
            cbR = colorbar(axR,'Location','eastoutside');
            cr  = axR.CLim; 
            cbR.Ticks      = [cr(1) cr(2)];
            cbR.TickLabels = {sprintf('%.1f',cr(1)), sprintf('%.1f',cr(2))};
            cbR.Label.String     = 'Intensity (a.u.)';
            t = cbR.Label;
            pos = t.Position; pos(1) = 1.2; t.Position = pos;
            cbR.Label.FontSize   = 12;
            cbR.FontSize         = 10;

            % remember for checkbox callback & reset checkbox
            s.Visual.ComplexField = ComplexField;
            s.Visual.ShowThickness = false;
            app.UIFigure.UserData = s;
            if isprop(app,'ThicknessCheckBox')
                app.ThicknessCheckBox.Value = false;
            end

            flower = s.Params.normalization_weights;          % matrix
            SingleCirc = s.Params.compensated_Circ_mask;          % logical mask
            Ratio = Compensation_SR.CalcEnhandcement(flower, SingleCirc);
            app.Information_compensation.Visible = 'on';
            app.Information_compensation.Text = sprintf('The estimated enhancement is %.2f\n times the original resolution', Ratio);

        end

        % Value changed function: ThicknessCheckBox
        function ThicknessCheckBoxValueChanged(app, event)
            showT = logical(event.Value);         % true when checked
            s = app.UIFigure.UserData;

            % get complex field we plotted
            if ~isstruct(s) || ~isfield(s,'Visual') || ~isfield(s.Visual,'ComplexField')
                uialert(app.UIFigure,'Please press "Visualize Results" first.','No data'); return
            end
            CF = s.Visual.ComplexField;

            axL = app.SRPhaseMap; cla(axL);

            if showT
                % --- Thickness map ---
                % need lambda (um) and refractive indices ns, nm
                if ~isfield(s,'Params') || ~all(isfield(s.Params,{'lambda_um','ns','nm'}))
                    uialert(app.UIFigure,'Missing parameters (λ, n_s, n_m).','Cannot compute thickness'); return
                end
                lambda = s.Params.lambda_um;  ns = s.Params.ns;  nm = s.Params.nm;
                dn = ns - nm;  if dn==0, uialert(app.UIFigure,'n_s - n_m is zero.','Cannot compute thickness'); return; end
                phaseMap = angle(CF);                     % wrapped phase [rad]
                Thic = (phaseMap * lambda) / (2*pi*dn);   % thickness in micrometers
                minThic = min(min(Thic));
                Thic = Thic - minThic;
                imshow(Thic, [], 'Parent', axL, 'InitialMagnification','fit');
                axis(axL,'image'); colormap(axL, "hot");  % nicer for scalar field
                title(axL, 'Thickness');
                cb = colorbar(axL,'Location','eastoutside');
                cl = axL.CLim; 
                cb.Ticks      = [cl(1) cl(2)];
                cb.TickLabels = {sprintf('%.3g',cl(1)), sprintf('%.3g',cl(2))};
                t = cb.Label; t.String='Thickness (\mum)'; t.Interpreter='tex';
                t.FontSize=12; 
                pos=t.Position; pos(1)=1.2;  t.Position=pos;
                cb.FontSize=10;
                s.Visual.ShowThickness = true;

            else
                % --- Back to phase map ---
                phaseMap = angle(CF);
                imshow(phaseMap, [], 'Parent', axL, 'InitialMagnification','fit');
                axis(axL,'image'); colormap(axL, 'gray');
                title(axL, 'Phase map');
                cb = colorbar(axL,'Location','eastoutside');
                cl = axL.CLim;
                cb.Ticks      = [cl(1) cl(2)];
                cb.TickLabels = {sprintf('%.2f',cl(1)), sprintf('%.2f',cl(2))};
                t = cb.Label; t.String='Phase (rad)'; t.FontSize=12;
                cb.FontSize         = 10;         % tick size
                pos=t.Position; pos(1)=1.2;  t.Position=pos;
                cb.FontSize=10;
                s.Visual.ShowThickness = false;
            end

            app.UIFigure.UserData = s;

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Button pushed function: SaveButton

            s = app.UIFigure.UserData;
            if ~isstruct(s) || ~isfield(s,'Results') || ~isfield(s,'ModeProcessing')
                uialert(app.UIFigure,'Please visualize/process results first.','Nothing to save'); return
            end

            % ---- pick complex SR field from processed mode ----
            if s.ModeProcessing == 1   % OI
                if ~isfield(s.Results,'OI') || ~isfield(s.Results.OI,'NormalizedSRComplexField')
                    uialert(app.UIFigure,'No OI result available.','Nothing to save'); return
                end
                CF = s.Results.OI.NormalizedSRComplexField;  modeLbl = "OI";
            else                       % SI
                if ~isfield(s.Results,'SI') || ~isfield(s.Results.SI,'NormalizedSRComplexField')
                    uialert(app.UIFigure,'No SI result available.','Nothing to save'); return
                end
                CF = s.Results.SI.NormalizedSRComplexField;  modeLbl = "SI";
            end

            % ---- what to save? (checkboxes) ----
            savePhase = logical(app.SavePhaseCheckBox.Value);
            saveThick = logical(app.SaveThickCheckBox.Value);
            saveFT    = logical(app.SaveFTCheckBox.Value);

            if ~(savePhase || saveThick || saveFT)
                uialert(app.UIFigure,'Please select at least one item to save.','No selection'); return
            end

            % ---- choose base folder once; remember it ----
            baseDir = "";
            if isfield(s,'LastSaveDir') && strlength(string(s.LastSaveDir))>0
                baseDir = string(s.LastSaveDir);
            end
            if baseDir == ""
                p = uigetdir(pwd,'Select a folder to save results');
                if isequal(p,0), return; end
                baseDir = string(p);
                s.LastSaveDir = baseDir;      % remember for next time
            end

            % ---- create timestamped subfolder ----
            stamp  = string(datetime('now','Format','yyyy-MM-dd_HHmmss'));
            outDir = fullfile(baseDir, "SR_" + modeLbl + "_" + stamp);
            if ~exist(outDir,'dir'); mkdir(outDir); end

            % ---- helpers for saving ----
            to16 = @(X) im2uint16(mat2gray(double(X)));  % scale robustly to 16-bit
            saved = strings(0,1);

            % ---- SR Phase map ----
            if savePhase
                P = angle(CF);                                  % radians
                imwrite(to16(P), fullfile(outDir, "SR_"+modeLbl+"_phase_rad.tif"));
                save(fullfile(outDir, "SR_"+modeLbl+"_phase_rad.mat"), 'P');
                saved(end+1) = "Phase map (rad)";
            end

            % ---- Thickness (µm) ----
            if saveThick
                if ~isfield(s,'Params') || ~all(isfield(s.Params,{'lambda_um','ns','nm'}))
                    uialert(app.UIFigure,'Missing parameters (λ, n_s, n_m). Thickness not saved.','Warning');
                else
                    lam = s.Params.lambda_um;  ns = s.Params.ns;  nm = s.Params.nm;
                    dn  = ns - nm;
                    if dn == 0
                        uialert(app.UIFigure,'n_s - n_m = 0. Thickness not saved.','Warning');
                    else
                        T_um = (angle(CF) * lam) / (2*pi*dn);    % µm
                        minthick = min(min(T_um));
                        T_um= T_um - minthick;
                        imwrite(to16(T_um), fullfile(outDir, "SR_"+modeLbl+"_thickness_um.tif"));
                        save(fullfile(outDir, "SR_"+modeLbl+"_thickness_um.mat"), 'T_um');
                        saved(end+1) = "Thickness (µm)";
                    end
                end
            end

            % ---- FT magnitude (log) ----
            if saveFT
                F = FT(CF);
                FTlog = (abs(F).^.15);                           % log magnitude
                h1 = mat2gray(FTlog);
                h1 = uint8(h1 * 255);
                imwrite(h1,gray(256), fullfile(outDir, "SR_"+modeLbl+"_FTmag_log.tif"));
                save(fullfile(outDir, "SR_"+modeLbl+"_FTmag_log.mat"), 'FTlog');
                saved(end+1) = "FT magnitude (log)";
            end

            % ---- persist state and notify ----
            app.UIFigure.UserData = s;
            uialert(app.UIFigure, ...
                sprintf("Saved to:\n%s\n\nFiles:\n- %s", outDir, strjoin(saved,newline+"- ")), ...
                'Saved');


            % ---------- Summary TXT ----------
            txtFile = fullfile(outDir, "SR_" + modeLbl + "_summary.txt");
            fid = fopen(txtFile,'w');

            fprintf(fid, "OpenSRQPI Reconstruction Summary\n");
            fprintf(fid, "Timestamp: %s\n", datestr(now,31));
            fprintf(fid, "Mode: %s\n", modeLbl);

            [H,W] = size(CF);
            fprintf(fid, "Field size: %dx%d   class: %s\n\n", H, W, class(CF));

            % --- Parameters used (dump everything in s.Params if present)
            fprintf(fid, "Parameters:\n");
            if isfield(s,'Params')
                fn = fieldnames(s.Params);
                for k = 1:numel(fn)
                    val = s.Params.(fn{k});
                    if isnumeric(val)
                        if isscalar(val)
                            sval = num2str(val, '%.6g');
                        else
                            sval = mat2str(val);
                        end
                    else
                        sval = char(string(val));
                    end
                    fprintf(fid, "  %s: %s\n", fn{k}, sval);
                end
            else
                fprintf(fid, "  (none)\n");
            end

            % --- Phase min/max (radians)
            P = angle(CF);
            fprintf(fid, "\nPhase (rad):   min = %.6g   max = %.6g\n", min(P(:)), max(P(:)));

            % --- Thickness min/max (µm) if we have what we need
            hasParams = isfield(s,'Params') && all(isfield(s.Params, {'lambda_um','ns','nm'}));
            if hasParams && (s.Params.ns - s.Params.nm) ~= 0
                lam = s.Params.lambda_um;   dn = s.Params.ns - s.Params.nm;
                T_um = (P * lam) / (2*pi*dn);   % thickness in micrometers
                minthick = min(min(T_um));
                T_um= T_um - minthick;
                fprintf(fid, "Thickness (um): min = %.6g   max = %.6g\n", min(T_um(:)), max(T_um(:)));
            else
                fprintf(fid, "Thickness (um): N/A (missing lambda/ns/nm or ns==nm)\n");
            end

            fclose(fid);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Group1 = uitabgroup(app.UIFigure);
            app.WelcomeOpenSRQPITab = uitab(app.Group1);
            app.WelcomeOpenSRQPITab.Title = 'Welcome OpenSRQPI';
            app.Label8 = uilabel(app.WelcomeOpenSRQPITab);
            app.Label8.Text = 'Welcome to OpenSRQPI';
            app.Label7 = uilabel(app.WelcomeOpenSRQPITab);
            app.Label7.Text = 'Please select one of the two available modules.';
            app.StructuredIlluminationDHMButton = uibutton(app.WelcomeOpenSRQPITab, 'push');
            app.StructuredIlluminationDHMButton.ButtonPushedFcn = createCallbackFcn(app, @StructuredIlluminationDHMButtonPushed, true);
            app.StructuredIlluminationDHMButton.Text = {'Structured Illumination'; 'DHM'};
            app.ObliqueIlluminationDHMButton = uibutton(app.WelcomeOpenSRQPITab, 'push');
            app.ObliqueIlluminationDHMButton.ButtonPushedFcn = createCallbackFcn(app, @ObliqueIlluminationDHMButtonPushed, true);
            app.ObliqueIlluminationDHMButton.Text = {'Oblique Illumination '; 'DHM'};
            app.Logo_OIRL = uiimage(app.WelcomeOpenSRQPITab);
            app.Logo_OIRL.ImageSource = fullfile(pathToMLAPP, 'logo_OIRL_nobackground.png');
            app.OIPreProcessingTab = uitab(app.Group1);
            app.OIPreProcessingTab.Title = 'OI Pre Processing';
            app.FT_OI = uiaxes(app.OIPreProcessingTab);
            title(app.FT_OI, 'Fourier Transform')
            app.FT_OI.XTick = [];
            app.FT_OI.XTickLabel = '';
            app.FT_OI.YTick = [];
            app.FT_OI.YTickLabel = '';
            app.FT_OI.Visible = 'off';
            app.Hologram_OI = uiaxes(app.OIPreProcessingTab);
            title(app.Hologram_OI, 'Hologram')
            zlabel(app.Hologram_OI, 'Z')
            app.Hologram_OI.XTick = [];
            app.Hologram_OI.XTickLabel = '';
            app.Hologram_OI.YTick = [];
            app.Hologram_OI.YTickLabel = '';
            app.Hologram_OI.Visible = 'off';
            app.LoadparametersforprocessingPanel = uipanel(app.OIPreProcessingTab);
            app.LoadparametersforprocessingPanel.Title = 'Load parameters for processing';
            app.PixelpitchumLabel = uilabel(app.LoadparametersforprocessingPanel);
            app.PixelpitchumLabel.Text = 'Pixel pitch (um)';
            app.PixelPitch_OI = uieditfield(app.LoadparametersforprocessingPanel, 'numeric');
            app.PixelPitch_OI.ValueChangedFcn = createCallbackFcn(app, @PixelPitch_OIValueChanged, true);
            app.WavelengthumLabel = uilabel(app.LoadparametersforprocessingPanel);
            app.WavelengthumLabel.Text = 'Wavelength (um)';
            app.Wavelength_OI = uieditfield(app.LoadparametersforprocessingPanel, 'numeric');
            app.Wavelength_OI.ValueChangedFcn = createCallbackFcn(app, @Wavelength_OIValueChanged, true);
            app.RegionforSpatialFilteringLabel = uilabel(app.LoadparametersforprocessingPanel);
            app.RegionforSpatialFilteringLabel.Text = 'Region for Spatial Filtering ';
            app.Region_OI = uieditfield(app.LoadparametersforprocessingPanel, 'numeric');
            app.Region_OI.ValueChangedFcn = createCallbackFcn(app, @Region_OIValueChanged, true);
            app.RefractiveindexforthesamplensubssubEditFieldLabel = uilabel(app.LoadparametersforprocessingPanel);
            app.RefractiveindexforthesamplensubssubEditFieldLabel.Interpreter = 'html';
            app.RefractiveindexforthesamplensubssubEditFieldLabel.Text = {'Refractive index'; 'for the sample (n<sub>s</sub>)'};
            app.RI_ns_OI = uieditfield(app.LoadparametersforprocessingPanel, 'numeric');
            app.RI_ns_OI.ValueChangedFcn = createCallbackFcn(app, @RI_ns_OIValueChanged, true);
            app.RefractiveindexforthemediumnimLabel = uilabel(app.LoadparametersforprocessingPanel);
            app.RefractiveindexforthemediumnimLabel.Interpreter = 'html';
            app.RefractiveindexforthemediumnimLabel.Text = {'Refractive index'; 'for the medium (n<sub>im</sub>)'};
            app.RI_nim_OI = uieditfield(app.LoadparametersforprocessingPanel, 'numeric');
            app.RI_nim_OI.ValueChangedFcn = createCallbackFcn(app, @RI_nim_OIValueChanged, true);
            app.Label3 = uilabel(app.LoadparametersforprocessingPanel);
            app.Label3.Text = 'Hint for selecting: ';
            app.Label2 = uilabel(app.LoadparametersforprocessingPanel);
            app.Label2.Text = {'L4 = (3x^2-1)/2'; 'L5 = xy'; 'L6 = (3y^2-1)/2'};
            app.Label1 = uilabel(app.LoadparametersforprocessingPanel);
            app.Label1.Text = {'L1 = 1'; 'L2 = x'; 'L3 = y '};
            app.SelectthemaximumLegendrepolinomialforcompensationLabel = uilabel(app.LoadparametersforprocessingPanel);
            app.SelectthemaximumLegendrepolinomialforcompensationLabel.Text = {'Select the maximum '; 'Legendre polinomial '; 'for compensation'};
            app.SelectLegendre_OI = uidropdown(app.LoadparametersforprocessingPanel);
            app.SelectLegendre_OI.Items = {'L1', 'L2', 'L3', 'L4', 'L5', 'L6'};
            app.SelectLegendre_OI.ValueChangedFcn = createCallbackFcn(app, @SelectLegendre_OIValueChanged, true);
            app.SelectLegendre_OI.Value = 'L1';
            app.Image = uiimage(app.LoadparametersforprocessingPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Hintregion.png');
            app.HintforselectingLabel = uilabel(app.LoadparametersforprocessingPanel);
            app.HintforselectingLabel.Text = {'Hint for '; 'selecting'};
            app.PreviousButton_OI = uibutton(app.OIPreProcessingTab, 'push');
            app.PreviousButton_OI.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_OIPushed, true);
            app.PreviousButton_OI.Visible = 'off';
            app.PreviousButton_OI.Text = 'Previous';
            app.NextButton_OI = uibutton(app.OIPreProcessingTab, 'push');
            app.NextButton_OI.ButtonPushedFcn = createCallbackFcn(app, @NextButton_OIPushed, true);
            app.NextButton_OI.Visible = 'off';
            app.NextButton_OI.Text = 'Next';
            app.Preview_OI = uibutton(app.OIPreProcessingTab, 'push');
            app.Preview_OI.ButtonPushedFcn = createCallbackFcn(app, @Preview_OIPushed, true);
            app.Preview_OI.Text = 'Preview holograms';
            app.LoadHologram_OI = uibutton(app.OIPreProcessingTab, 'push');
            app.LoadHologram_OI.ButtonPushedFcn = createCallbackFcn(app, @LoadHologram_OIPushed, true);
            app.LoadHologram_OI.Text = 'Load holograms';
            app.ProcessHologram_OI = uibutton(app.OIPreProcessingTab, 'push');
            app.ProcessHologram_OI.ButtonPushedFcn = createCallbackFcn(app, @ProcessHologram_OIPushed, true);
            app.ProcessHologram_OI.Text = 'Process Holograms';
            app.SIPreProcessingTab = uitab(app.Group1);
            app.SIPreProcessingTab.Title = 'SI Pre Processing';
            app.FT_SI = uiaxes(app.SIPreProcessingTab);
            title(app.FT_SI, 'Fourier Transform')
            app.FT_SI.XTick = [];
            app.FT_SI.XTickLabel = '';
            app.FT_SI.YTick = [];
            app.FT_SI.YTickLabel = '';
            app.FT_SI.Visible = 'off';
            app.Hologram_SI = uiaxes(app.SIPreProcessingTab);
            title(app.Hologram_SI, 'Hologram')
            zlabel(app.Hologram_SI, 'Z')
            app.Hologram_SI.XTick = [];
            app.Hologram_SI.XTickLabel = '';
            app.Hologram_SI.YTick = [];
            app.Hologram_SI.YTickLabel = '';
            app.Hologram_SI.Visible = 'off';
            app.LoadparametersforprocessingPanel_2 = uipanel(app.SIPreProcessingTab);
            app.LoadparametersforprocessingPanel_2.Title = 'Load parameters for processing';
            app.PixelpitchumEditField_2Label = uilabel(app.LoadparametersforprocessingPanel_2);
            app.PixelpitchumEditField_2Label.Text = 'Pixel pitch (um)';
            app.PixelPitch_SI = uieditfield(app.LoadparametersforprocessingPanel_2, 'numeric');
            app.PixelPitch_SI.ValueChangedFcn = createCallbackFcn(app, @PixelPitch_SIValueChanged, true);
            app.WavelengthumEditField_2Label = uilabel(app.LoadparametersforprocessingPanel_2);
            app.WavelengthumEditField_2Label.Text = 'Wavelength (um)';
            app.Wavelength_SI = uieditfield(app.LoadparametersforprocessingPanel_2, 'numeric');
            app.Wavelength_SI.ValueChangedFcn = createCallbackFcn(app, @Wavelength_SIValueChanged, true);
            app.RegionforSpatialFilteringLabel_2 = uilabel(app.LoadparametersforprocessingPanel_2);
            app.RegionforSpatialFilteringLabel_2.Text = 'Region for Spatial Filtering ';
            app.Region_SI = uieditfield(app.LoadparametersforprocessingPanel_2, 'numeric');
            app.Region_SI.ValueChangedFcn = createCallbackFcn(app, @Region_SIValueChanged, true);
            app.RefractiveindexforthesamplensubssubEditField_2Label = uilabel(app.LoadparametersforprocessingPanel_2);
            app.RefractiveindexforthesamplensubssubEditField_2Label.Interpreter = 'html';
            app.RefractiveindexforthesamplensubssubEditField_2Label.Text = {'Refractive index'; 'for the sample (n<sub>s</sub>)'};
            app.RI_ns_SI = uieditfield(app.LoadparametersforprocessingPanel_2, 'numeric');
            app.RI_ns_SI.ValueChangedFcn = createCallbackFcn(app, @RI_ns_SIValueChanged, true);
            app.RefractiveindexforthemediumnsubimsubEditField_2Label = uilabel(app.LoadparametersforprocessingPanel_2);
            app.RefractiveindexforthemediumnsubimsubEditField_2Label.Interpreter = 'html';
            app.RefractiveindexforthemediumnsubimsubEditField_2Label.Text = {'Refractive index'; 'for the medium (n<sub>im</sub>)'};
            app.RI_nim_SI = uieditfield(app.LoadparametersforprocessingPanel_2, 'numeric');
            app.RI_nim_SI.ValueChangedFcn = createCallbackFcn(app, @RI_nim_SIValueChanged, true);
            app.label6 = uilabel(app.LoadparametersforprocessingPanel_2);
            app.label6.Text = 'Hint for selecting: ';
            app.label5 = uilabel(app.LoadparametersforprocessingPanel_2);
            app.label5.Text = {'L4 = (3x^2-1)/2'; 'L5 = xy'; 'L6 = (3y^2-1)/2'};
            app.Label4 = uilabel(app.LoadparametersforprocessingPanel_2);
            app.Label4.Text = {'L1 = 1'; 'L2 = x'; 'L3 = y '};
            app.SelectthemaximumLegendrepolinomialforcompensationDropDown_2Label = uilabel(app.LoadparametersforprocessingPanel_2);
            app.SelectthemaximumLegendrepolinomialforcompensationDropDown_2Label.Text = {'Select the maximum '; 'Legendre polinomial '; 'for compensation'};
            app.SelectLegendre_SI = uidropdown(app.LoadparametersforprocessingPanel_2);
            app.SelectLegendre_SI.Items = {'L1', 'L2', 'L3', 'L4', 'L5', 'L6'};
            app.SelectLegendre_SI.ValueChangedFcn = createCallbackFcn(app, @SelectLegendre_SIValueChanged, true);
            app.SelectLegendre_SI.Value = 'L1';
            app.Image_2 = uiimage(app.LoadparametersforprocessingPanel_2);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Hintregion.png');
            app.HintforselectingLabel_2 = uilabel(app.LoadparametersforprocessingPanel_2);
            app.HintforselectingLabel_2.Text = {'Hint for '; 'selecting'};
            app.PreviousButton_SI = uibutton(app.SIPreProcessingTab, 'push');
            app.PreviousButton_SI.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_SIPushed, true);
            app.PreviousButton_SI.Visible = 'off';
            app.PreviousButton_SI.Text = 'Previous';
            app.NextButton_SI = uibutton(app.SIPreProcessingTab, 'push');
            app.NextButton_SI.ButtonPushedFcn = createCallbackFcn(app, @NextButton_SIPushed, true);
            app.NextButton_SI.Visible = 'off';
            app.NextButton_SI.Text = 'Next';
            app.Preview_SI = uibutton(app.SIPreProcessingTab, 'push');
            app.Preview_SI.ButtonPushedFcn = createCallbackFcn(app, @Preview_SIButtonPushed, true);
            app.Preview_SI.Text = 'Preview holograms';
            app.LoadHologram_SI = uibutton(app.SIPreProcessingTab, 'push');
            app.LoadHologram_SI.ButtonPushedFcn = createCallbackFcn(app, @LoadHologram_SIButtonPushed, true);
            app.LoadHologram_SI.Text = 'Load holograms';
            app.ProcessHologram_SI = uibutton(app.SIPreProcessingTab, 'push');
            app.ProcessHologram_SI.ButtonPushedFcn = createCallbackFcn(app, @ProcessHologram_SIButtonPushed, true);
            app.ProcessHologram_SI.Text = 'Process Holograms';
            app.ParingofthehologramsPanel = uipanel(app.SIPreProcessingTab);
            app.ParingofthehologramsPanel.Title = 'Paring of the holograms';
            app.Warning = uilabel(app.ParingofthehologramsPanel);
            app.Warning.Text = {'Remeber: for processing the SI hologram, we need two (2) '; 'holograms per orientation, with an unknow phase jump. '; 'Pair them here.'};
            app.SRvisualizationTab = uitab(app.Group1);
            app.SRvisualizationTab.Title = 'SR visualization';
            app.SRPhaseMap = uiaxes(app.SRvisualizationTab);
            app.SRPhaseMap.XTick = [];
            app.SRPhaseMap.YTick = [];
            app.SRPhaseMap.Visible = 'off';
            app.SRFT = uiaxes(app.SRvisualizationTab);
            app.SRFT.XTick = [];
            app.SRFT.YTick = [];
            app.SRFT.Visible = 'off';
            app.SavingPanel = uipanel(app.SRvisualizationTab);
            app.SavingPanel.Title = 'Saving';
            app.SavePhaseCheckBox = uicheckbox(app.SavingPanel);
            app.SavePhaseCheckBox.Text = 'Save the SR phase map';
            app.SaveFTCheckBox = uicheckbox(app.SavingPanel);
            app.SaveFTCheckBox.Text = 'Save the SR Fourier Transform';
            app.SaveThickCheckBox = uicheckbox(app.SavingPanel);
            app.SaveThickCheckBox.Text = 'Save the Thickness';
            app.SaveButton = uibutton(app.SavingPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ThicknessCheckBox = uicheckbox(app.SRvisualizationTab);
            app.ThicknessCheckBox.ValueChangedFcn = createCallbackFcn(app, @ThicknessCheckBoxValueChanged, true);
            app.ThicknessCheckBox.Text = 'Thickness';
            app.InformationofthevisualizationPanel = uipanel(app.SRvisualizationTab);
            app.InformationofthevisualizationPanel.Title = 'Information of the visualization';
            app.Information_compensation = uilabel(app.InformationofthevisualizationPanel);
            app.Information_compensation.Visible = 'off';
            app.Image2 = uiimage(app.InformationofthevisualizationPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'logo_OIRL_nobackground.png');
            app.VisualizeResultsButton = uibutton(app.SRvisualizationTab, 'push');
            app.VisualizeResultsButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeResultsButtonPushed, true);
            app.VisualizeResultsButton.Text = 'Visualize Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OPEN_SR_QPI_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end