classdef SR_QPI_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OpenSRQPIUIFigure              matlab.ui.Figure
        Welcome                        matlab.ui.container.TabGroup
        UMassDOIRLTab                  matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        WelcomeLabel                   matlab.ui.control.Label
        StructuredIlluminationButton   matlab.ui.control.Button
        ObliqueIlluminationButton      matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        ObliqueilluminationpreparationTab  matlab.ui.container.Tab
        ConjugateCheckBox              matlab.ui.control.CheckBox
        RegionEditFieldLabel           matlab.ui.control.Label
        RegionEditField                matlab.ui.control.NumericEditField
        FTPSwitch                      matlab.ui.control.Switch
        Label                          matlab.ui.control.Label
        PL                             matlab.ui.control.Button
        PR                             matlab.ui.control.Button
        WavelengthumEditField          matlab.ui.control.NumericEditField
        WavelengthumEditFieldLabel     matlab.ui.control.Label
        PixelSizeYumEditFieldLabel     matlab.ui.control.Label
        PixelSizeXumEditField          matlab.ui.control.NumericEditField
        PixelSizeXumEditFieldLabel     matlab.ui.control.Label
        PixelSizeYumEditField          matlab.ui.control.NumericEditField
        ParametersPanel                matlab.ui.container.Panel
        RegionDropDown                 matlab.ui.control.DropDown
        FTRegion1DropDownLabel         matlab.ui.control.Label
        samPerams                      matlab.ui.control.CheckBox
        tFile                          matlab.ui.control.EditField
        SaveBox                        matlab.ui.control.CheckBox
        LoadhologramsButton            matlab.ui.control.Button
        OIPhaseReconstructionButton    matlab.ui.control.Button
        AmpAx                          matlab.ui.control.UIAxes
        PhaseAx                        matlab.ui.control.UIAxes
        StructuredIlluminaionpreparationTab  matlab.ui.container.Tab
        StructRun                      matlab.ui.control.Button
        ParametersPanel_S              matlab.ui.container.Panel
        RegionDropDown_S               matlab.ui.control.DropDown
        FTRegion1Label                 matlab.ui.control.Label
        WavelengthumEditField_S        matlab.ui.control.NumericEditField
        WavelengthumEditField_2Label   matlab.ui.control.Label
        PixelSizeXumEditField_S        matlab.ui.control.NumericEditField
        PixelSizeXumEditField_2Label   matlab.ui.control.Label
        PixelSizeYumEditField_S        matlab.ui.control.NumericEditField
        PixelSizeYumEditField_2Label   matlab.ui.control.Label
        samPerams_2                    matlab.ui.control.CheckBox
        gPreveiw                       matlab.ui.control.Button
        gN                             matlab.ui.control.Button
        gP                             matlab.ui.control.Button
        ftSwitch                       matlab.ui.control.Switch
        gSelect                        matlab.ui.container.Panel
        groupNUM                       matlab.ui.control.NumericEditField
        NumberofdifferntanglesLabel    matlab.ui.control.Label
        StructLoad                     matlab.ui.control.Button
        groupPrev                      matlab.ui.control.UIAxes
        SRPhaseTab                     matlab.ui.container.Tab
        InformationPanel               matlab.ui.container.Panel
        ThicknessCheckBox              matlab.ui.control.CheckBox
        Stats                          matlab.ui.control.Label
        ThickSwitch                    matlab.ui.control.Switch
        Image_2                        matlab.ui.control.Image
        Paramiters2                    matlab.ui.container.Panel
        saveThick                      matlab.ui.control.CheckBox
        SRSave                         matlab.ui.control.CheckBox
        NIM                            matlab.ui.control.NumericEditField
        RefactiveIndexMediumnimLabel   matlab.ui.control.Label
        NS                             matlab.ui.control.NumericEditField
        RefractiveIndexSamplen_sLabel  matlab.ui.control.Label
        SRQPIMapButton                 matlab.ui.control.Button
        threshAx                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        IS_WORKING 
        COMPLEX_FEILD % stores complexfeilts OI
        IMAGES % all loaded images
        CURRENT_IMG % current image being displayed
        PHASES % generated Phase images
        CURRENT_PHASE % current phase being displayed
        TOTAL_IMG % # of img loaded
        PERAMS % arguments for da math
        SAVED % keeps tracks of when a peram is saved
        FILTERS % colections of all filters
        CIRCS % colections of all circles 
        STATE = "init" % where the app is in the select process
        dRegion = 1; % default region 
        SUMF % sum of filters
        SUMC % sum of circs
        CircFinal % for the lateral res factor
        MaskThic % power spec
        MAP % power spec mask
        DROPDOWN cell % keep track of eah holo drop down 
        GROUPS % orginize holos for si 
        NUM_GROUPS % number of groups
        MODE % Si or OI
        GROUP_LIM % amount of holo in each group
        ORDER % used for mini SI
        MaskPhase % stores phase from power spec
        DXY % save dx and dy val
        THRESH % saves threshold SI
        COMP_FT = {};   % cell array of per-component normalized FTs (complex)
        COMP_MASK = {}; % cell array of per-component binary masks

        RADII % Description
    end

    methods (Access = private)
        function FTn = normalizeFTUnit(app, FT)
            % Scales the magnitude to [0,1] while preserving complex phase
            a = abs(FT);
            m = max(a(:));
            if m == 0
                FTn = FT;
            else
                FTn = FT ./ m;
            end
        end

        function [results] = setVis(app, componet, state)
            if length(componet) > 1
                for i = 1:length(componet)
                    componet(i).Visible = state;
                    if componet(i) == "matlab.ui.control.UIAxes"
                        axis(componet(i), 'off')
                    end
                end
            else
                componet.Visible = state;
                if componet == "matlab.ui.control.UIAxes"
                        axis(componet, 'off')
                end

                results = 0;
            end

        end

        function results = getVal(app, componet)
            app = app;
            results = componet.Value;

        end

        function ComplexFieldNorm = phaseNorm(app,ComplexField)
            phase = angle(ComplexField);
            MIN = min(min(phase)); % idk why double min
            ComplexFieldNorm = ComplexField.*exp(-1j*MIN);
        end

        function  [holo_rec2, phaseImg] = Ophase_rec(app,fil, dx, dy, lambda, region, save)
            % Get the file name components (path, name, extension)
            [path,name,ext] = fileparts(fil);

            % Read hologram image and convert it to double precision
            holo = double(imread(fil));
            % Get the size of the hologram
            [N,M] = size(holo);
            % Create a meshgrid for the hologram
            [m,n] = meshgrid(-M/2:M/2-1,-N/2:N/2-1);
            % Calculate the Fourier Transform of the hologram
            % and shift the zero-frequency component to the center
            ft_holo = fftshift(fft2(fftshift(holo)));
            %figure(15), imagesc(log(abs(ft_holo).^2)), title 'FT hologram'

            % Initialize a filter with zeros
            filter = zeros(N,M);
            % Create a filter mask for the desired region

            if region== 1
                filter(50:round(N/2-(N*0.19)),round(M/2+(M*0.18)):M-50) = 1; % 1nd quadrant
            elseif region== 2
                filter(1:round(N/2-(N*0.185)),1:round(M/2-(M*0.185))) = 1;  % 2nd quadrant
            elseif region== 3
                filter(round(N/2+(N*0.15)):N-40,40:round(M/2-(M*0.18))) = 1; % 3nd quadrant
            else
                filter(round(N/2+(N*0.075)):N-500,round(M/2+(M*0.07)):M-600) = 1; % 4nd quadrant
            end
            % Apply the filter to the Fourier Transform of the hologram
            ft_filtered_holo = ft_holo .* filter;

            filtered_spect = log(abs(ft_filtered_holo).^2);
            BW = imbinarize(filtered_spect, 12); %FOR CONTINOUS
            %figure;colormap gray;imagesc(BW);axis image, title 'BW'

            % Limpiar la imagen, dejando solo la región más grande (círculo)
            BW = bwareafilt(BW, 1);

            % Rellenar el círculo binario para obtener un borde continuo y robusto
            BW_filled = imfill(BW, 'holes');

            % Detectar el borde del círculo rellenado
            edges = edge(BW_filled, 'Canny');

            % Extraer coordenadas del borde
            [y,x] = find(edges);

            % Ajuste robusto por mínimos cuadrados para círculo
            A = [-2*x, -2*y, ones(size(x))];
            b = -(x.^2 + y.^2);
            params = A\b;

            % Parámetros del círculo
            center(1) = params(1);
            center(2) = params(2);
            radius = sqrt(center(1)^2 + center(2)^2 - params(3));

            % Resultados numéricos
            fprintf('Centro detectado: (%.2f, %.2f)\n', center(1), center(2));
            fprintf('Radio detectado: %.2f píxeles\n', radius);
            
             % Create a new circular filter mask based on the fitted circle parameters.
            circ = ones(N);
            for r = 1:N
                for p = 1:N
                    if sqrt((r-center(2))^2+(p-center(1))^2)>radius
                        circ(r,p) = 0;
                    end
                end
            end
            filtered_spect = ft_filtered_holo.*circ;
            %figure;colormap gray;imagesc(log(abs(filtered_spect)));title 'filtrado circular'


            [~,idx] = max(filtered_spect(:));
            % Get the maximum values of fx and fy
            [fy_max,fx_max] = ind2sub([N,M],idx);
            holo_rec = fftshift(ifft2(fftshift(filtered_spect)));
            % Find the maximum value in the filtered spectrum
            % Define wavenumber
            k = 2 * pi / lambda;
            % Calculate the center frequencies for fx and fy
            fx_0 = M/2;
            fy_0 = N/2;

            % Calculate the angles for the compensation wave
            theta_x = asin((fx_0 - fx_max +1) * lambda / (M * dx));
            theta_y = asin((fy_0 - fy_max +1) * lambda / (N * dy));
            % Calculate the reference wave
            ref = exp(1i * k * (sin(theta_x) * m * dx + sin(theta_y) * n * dy));
            % Apply the reference wave to the hologram reconstruction
            holo_rec2 = holo_rec .* (-ref);

            phase = angle(holo_rec2);
            %figure, imagesc(phase), colormap gray, title 'Phase reconstruida'
            %phaseImg = imagesc(phase);
            FT_holo = FT(holo_rec2);
            %figure, imagesc(log(abs(FT_holo).^2)), colormap gray, title 'FT centrado'

             % Check if the 'save' flag is set to 1
            if save == 1
                % Create a folder with the current date to store the results.
                fol = string(datetime("today","Format","uuuu-MM-dd"));

                % Create a metadata string with the parameters used for reconstruction.
                met = strcat("dx:",string(dx),"\n","dy: ",string(dy),"\n","region: ",string(region),"\n","lambda: ",string(lambda));

                % Create the directory if it doesn't exist.
                mkdir(fol);
                % Define the filename for the saved phase image.
                phaseName = strcat(fol,"\",name,"_OI_DHM_output",ext);

                % Define the filename for the parameters text file.
                tN = strcat(fol,"\",name,"_Params.txt");
                % make txt with params 
                writelines(met,tN,"WriteMode","overwrite");
                imwrite(phase,phaseName);
            end

        end

        function [newFilter, circ, rad] = circular_filter(app,ComplexField, newCompleField_0, TH)
            CF0 = FT(ComplexField);
            CF0_BW = imbinarize(log(abs(CF0).^2), TH);
            % %figure, imagesc(CF0_BW)
            [filtrado0, circ0, rad] = app.findcircle(CF0_BW, CF0);
            newFilter = FT(newCompleField_0).*circ0;
            circ = circ0;
        end

        function [filter, circ, rad] = findcircle(app,CF0_BW, CF0)
            %Threshold the phase image
            app = app;
            BW = CF0_BW;
            [N,M] = size(CF0_BW);
            %figure;colormap gray;imagesc(BW);axis image, title 'BW'

            % Limpiar la imagen, dejando solo la región más grande (círculo)
            BW = bwareafilt(BW, 1);

            % Rellenar el círculo binario para obtener un borde continuo y robusto
            BW_filled = imfill(BW, 'holes');

            % Detectar el borde del círculo rellenado
            edges = edge(BW_filled, 'Canny');

            % Extraer coordenadas del borde
            [y,x] = find(edges); 

            % Ajuste robusto por mínimos cuadrados para círculo
            A = [-2*x, -2*y, ones(size(x))];
            b = -(x.^2 + y.^2);
            params = A\b;

            % Parámetros del círculo
            center(1) = params(1);
            center(2) = params(2);
            radius = sqrt(center(1)^2 + center(2)^2 - params(3));
            rad = radius;

            % Resultados numéricos
            fprintf('Centro detectado: (%.2f, %.2f)\n', center(1), center(2));
            fprintf('Radio detectado: %.2f píxeles\n', radius);

            circ = ones(N);
            for r = 1:N
                for p = 1:N
                    if sqrt((r-center(2))^2+(p-center(1))^2)>radius
                        circ(r,p) = 0;
                    end
                end
            end

            filter = CF0.*circ;
        end

        function [filt, circ, compfeild, rad,phaseImg] = phaseFilter(app,file, dx, dy, lambda, region, save)
            % Perform off-axis holographic reconstruction using the Ophase_rec function.
            % This returns the raw reconstructed complex field.
            complexFeild  = app.Ophase_rec(file, dx,dy,lambda,region, save);

            % Store the raw complex field in an output variable.
            compfeild = complexFeild;
            % Calculate the phase of the reconstructed complex field.
            phaseImg = angle(complexFeild);
           
            % Get the dimensions of the complex field.
            [X,Y] = size(compfeild);

             % Normalize the phase of the complex field by its minimum value.
            % The phaseNorm function shifts the phase so the minimum value is zero.
            zero_Phase = app.phaseNorm(complexFeild);
            %calculates the thickness of the object from the phase information
            app.thickness(zero_Phase,X,Y, lambda,1.52,1,0);

            % Apply a circular filter to the complex field to refine the reconstruction.
            % This function returns the filtered field, the circular mask, and its radius.
            [f, c, rad] = app.circular_filter(complexFeild,zero_Phase,12);
            filt = f;
            circ = c;

        end

        function [THICC, thicImg] = thickness(app, phaseDeg, x, y , lambda, ns, nim, check)
            
            THICC= (phaseDeg * lambda)/ (2* pi * (ns-nim));

            if check == 1 % creates plot to check if thickness center allines w/ center
                %disp(1)
                prefil = improfile(THICC, x,y);
                %figure(8), imagesc(THICC), colormap hot, title '0 deg thickness'
                hold on
                plot(x, y, 'c-', 'LineWidth', 2); % 'c-' for cyan line
                hold off
            end
        end

        function [mask_RAW, thickness, phase, ratio] = phaseSum(app, filters, circs, ns, nim, lambda, save)

            % inF = IFT(filters);
            normalization_weights = 1 ./ circs; % Inverso de la suma en cada punto
            normalization_weights(isinf(normalization_weights)) = 0; % Manejar división por cero
            normalized_circles = filters.* normalization_weights;
            % Generate the super-resolved power spectrum image (mask_RAW) from the
            mask_RAW = log(abs(normalized_circles).^2);% returns the flower image
            %used for testing
            map = log(abs(normalized_circles));
            % Perform the Inverse Fourier Transform on the normalized complex field
            % to get the final super-resolved complex field.
            inFL = IFT(normalized_circles);
            % Calculate the phase of the final super-resolved field.
            pSD = angle(inFL);
            phase = pSD;
            
            % Calculate the thickness map based on the super-resolved phase,
            % wavelength, and refractive indices of the sample and medium.
            thickness = (pSD * lambda)/ (2* pi * (ns-nim));% return the thick ness
            %disp([app.MODE, (app.CircFinal), app.THRESH])

            % Determine the thresholding method based on the app's mode.
            if lower(app.MODE) == "si"
                rF = app.RADII;
             
            else
                rF = app.CircFinal;
            % For mode oi, binarize the amplitude map and find a circle.
            % mBW = imbinarize(map);
            % [f,c, rad] = app.findcircle(mBW, mask_RAW);%move to phase sum
            end
            [N,M] = size(map);
            BW = imbinarize(normalization_weights);
            yi = round(N/2);
            pro = improfile(BW, [1,M],[yi,yi]);

            xA = find(pro == 1, 1, "first");
            xB = find(pro == 1, 1, "last");
            disp(["A", xA, "B", xB, "D?", xB-xA])
            rad = (xB-xA)/2;
            disp(["ratio (RADII)" round(rad./rF,2)])
           
            % Calculate the ratio of improvement by comparing the radius of the
            % super-resolved filter to the original reconstruction filter radius.
            ratio = round(rad./rF,2);
            %disp(ratio);

            % Find the maximum and minimum values of the thickness and phase for metadata.
            tmax = max(thickness(:));
            tmin = min(thickness(:));
            pmax = max(phase(:));
            pmin = min(phase(:));

            % Check if the 'save' flag is set.
            if save == 1 
                % Set up file and folder names for saving the results.
                ext = ".bmp";
                fol = string(datetime("today","Format","uuuu-MM-dd"));
                fol = strcat("SR_Phase_",fol);
                mkdir(fol);

                % Create metadata text with key parameters.
                met = [
                    "Refractive Index Sample (ns):" + string(ns);
                    "Refactive Index Medium (nim): " + string(nim);
                    "Wavelength (um): " + string(lambda);
                    "Pixel size x,y (um): " + string(app.DXY(1))+","+string(app.DXY(2));
                    "Ratio of improvement: " + string(ratio);
                    "Max Phase (rad): "+ string(pmax);
                    "Minimum phase (rad): "+ string(pmin);
                    "Max thickness (um): "+ string(tmax);
                    "Minimum thickness (um): "+ string(tmin)
                    ];
                
                % Define filenames for the output images and parameter file.
                thickName = strcat(fol,"\","Thickness","_",app.MODE,"_DHM_output",ext);
                phaseName = strcat(fol,"\","Phase","_",app.MODE,"_DHM_output",ext);
                maskName = strcat(fol,"\","SR_Phase_Map","_",app.MODE,"_DHM_output",ext);
                
                 % Save the thickness map if the 'saveThick' checkbox is checked.
                if app.saveThick.Value == 1
                    thick_mat2gray = mat2gray(thickness);%Convert a numeric matrix to a grayscale intensity image from 0 to 1
                    thick_uint8 = uint8(255*thick_mat2gray);
                    imwrite(thick_uint8,thickName);
                else
                    % If not saving thickness, remove thickness-related lines from metadata.
                    met([8,9]) = [];
                end 

                 % Save the metadata to a text file.
                tN = strcat(fol,"\","SR_Phase","_Params.txt");
                writelines(met,tN,"WriteMode","overwrite");
                
                % Save the super-resolved amplitude map.
                % This section creates an invisible figure to render the image
                % with a gray colormap and saves the frame as an image file.
                hFig = figure('Visible', 'off');
                hAx = axes(hFig); % Create axes in the invisible figure
                hAx.Visible = "off";
                hAx.XTick = [];
                hAx.YTick = [];
                hAx.XTickLabel = '';
                hAx.YTickLabel = '';
                hAx.Box = 'off';
                imagesc(hAx, mask_RAW); % Plot your data in the hidden axes
                colormap(hAx, 'gray'); % Ensure colormap is set if you want a specific one
                % Get the frame from the hidden figure/axes
                F = getframe(hAx); % Or getframe(hFig); if you want the whole invisible figure
                imwrite(F.cdata, maskName);
                close(hFig);

                % Save the super-resolved phase map.
                phaseSR_mat2gray = mat2gray(phase);%Convert a numeric matrix to a grayscale intensity image from 0 to 1
                phaseSR_uint8 = uint8(255*phaseSR_mat2gray);
                imwrite(phaseSR_uint8,phaseName);
                

            end
        end
        
        function results = regionRec(app, axis, region)
             w = xlim(axis);
             w = w(2)/2;
             h = ylim(axis);
             h = h(2)/2;
            switch region

                case 1
                    rectangle(axis, Position= [w 0 w h]);
                case 2
                    rectangle(axis,Position= [0 0 w h] );
                case 3
                    rectangle(axis, Position= [0 h w h]);
                case 4
                    rectangle(axis,Position= [w h w h] );
                    
            end

            
        end
        
        function results = setAxis(app, uiAxis, a, vis, title, cMap)
            colormap(uiAxis, cMap);
            if title == "0"
                uiAxis.Title.String = uiAxis.Title.String;
            else
                uiAxis.Title.String = title;
            end
            uiAxis.Visible = vis;
            axis(uiAxis, a);
            results = 1;
            
        end
        
        function results = setAxisLim(app, ui, x, y)
                ui.XLim = [0,x];
                ui.YLim = [0,y];
                results = 1;
            
        end
        
        function threshold = getThresh(app,filterdHolo)
             % Get the dimensions of the filtered hologram.
            [x,y] = size(filterdHolo);

            % Initialize p1M by finding the minimum log-magnitude in the first column.
            % This is an initial guess for the threshold.
            p1M = min(log(abs(filterdHolo(:,1))));

            % Initialize a step size for the while loop.
            step = 100;
            i = x;
            while p1M < 0
                p1M = min(log(abs(filterdHolo(1:i,1))));
                %disp(p1M)
                i = i - step;
            end
            threshold = 1.2*p1M;

        end
        
        function [circ2, radius] = circ_Struc(app,H1Filt,TH, N)

            BW = imbinarize(abs(H1Filt).^.2, TH);
            %figure; imshow(BW);
            BW = bwareafilt(BW, 1);
            BW_filled = imfill(BW, 'holes');
            edges = edge(BW_filled, 'Canny');
            
            [y,x] = find(edges);
            A = [-2*x, -2*y, ones(size(x))];
            b = -(x.^2 + y.^2);
            params = A\b;
            center(1) = params(1);
            center(2) = params(2);
            radius = ceil(sqrt(center(1)^2 + center(2)^2 - params(3)));

            circ2 = ones(N);
            for r = 1:N
                for p = 1:N
                    if sqrt((r-center(2))^2+(p-center(1))^2)>radius
                        circ2(r,p) = 0;
                    end
                end
            end
           
        end
        
       
        function results = StateSwitch(app, src, event)
            %this is how the main menu works

            if src == app.ObliqueIlluminationButton
                %msgbox("oi")
                app.Welcome.Visible = "off";
                app.TabGroup.Visible = "on";
                delete(app.StructuredIlluminaionpreparationTab)
                app.STATE = 'init';
                app.MODE = "OI";
            elseif src == app.StructuredIlluminationButton
                %msgbox("si")
                app.Welcome.Visible = "off";
                app.TabGroup.Visible = "on";
                delete(app.ObliqueilluminationpreparationTab)
                app.STATE = 'init';
                app.MODE = "SI";
            end
            
        end
        
        function [Cmax1, Rmax1, Cmax2 , Rmax2] = max_holo(app,H1Filt)
            % Calculate the absolute magnitude of the complex field.
            AbsFT = abs(H1Filt);
            maxValue = max(AbsFT(:));%value of the maximum peaks
            % Find the row and column indices of the first maximum value.
            [rowsofMaxPk, colsofMaxPk] = find(AbsFT == maxValue);
            Cmax1 = colsofMaxPk;%Horizontal position of the peak of Dplus
            Rmax1 = rowsofMaxPk;%Vertical position of the peak of Dplus
            % Clear temporary variables to free up memory
            clear maxValue rowsofMaxPk colsofMaxPk

            AbsFT(Rmax1-2:Rmax1+2,Cmax1-2:Cmax1+2) = 0;
            %figure; colormap gray; imagesc(AbsFT);axis image;
            maxValue = max(AbsFT(:));%value of the maximum peaks
            [rowsofMaxPk, colsofMaxPk] = find(AbsFT == maxValue);
            Cmax2 = colsofMaxPk;%Horizontal position of the peak of Dminus
            Rmax2 = rowsofMaxPk;%Vertical position of the peak of Dminus
            % Clear temporary variables to free up memory
            clear AbsFT maxValue rowsofMaxPk colsofMaxPk
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%% SOFI ADDED %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % ==========================================
        % minimaztion_heuritic  (G+)
        % Usage:
        %   [Gplus, theta_best, cost_best] = minimaztion_heuritic(app, holo_FT, fx_max, fy_max);
        %   [Gplus, theta_best, cost_best] = minimaztion_heuritic(app, holo_FT, fx_max, fy_max, prevTheta);
        % ==========================================
        function [Gplus_demod, theta_best, cost_best] = minimaztion_heuritic(app, holo_FT, fx_max, fy_max, prevTheta)

            if nargin < 6, prevTheta = []; end
            holo_FT = im2double(holo_FT);   % make sure type is consistent

            % --- bounds: full on first run; narrowed on repeat ---
            if ~isempty(prevTheta) && isfinite(prevTheta)
                delta = 10*pi/180; % ±10 degrees
                lb = max(0,     prevTheta - delta);
                ub = min(2*pi,  prevTheta + delta);
            else
                lb = 0;  ub = 2*pi;
            end

            % --- PSO options (robust) ---
            baseOpts = optimoptions('particleswarm', ...
                'Display','off', ...
                'SwarmSize', 4, ...
                'MaxIterations', 100, ...
                'MaxStallIterations', 50, ...
                'FunctionTolerance', 1e-6);

            % Multi-start light
            nStarts = 3;
            theta_best = NaN; cost_best = inf;

            costfun = @(param) function_heuristic.costFunction_SIDHM(param, holo_FT, fx_max, fy_max);

            for s = 1:nStarts
                % Initial swarm around prevTheta if available, otherwise random in [lb,ub]
                if ~isempty(prevTheta) && isfinite(prevTheta)
                    seed = prevTheta;
                else
                    seed = lb + (ub-lb).*rand(1,1);
                end

                initSwarm = repmat(seed, baseOpts.SwarmSize, 1) + (pi/180)*randn(baseOpts.SwarmSize,1); % jitter ±1°
                initSwarm = max(lb, min(ub, initSwarm));  % clamp

                opts = optimoptions(baseOpts, 'InitialSwarmMatrix', initSwarm);

                [theta, cost] = particleswarm(costfun, 1, lb, ub, opts);

                if cost < cost_best
                    cost_best  = cost;
                    theta_best = theta;
                end
                fprintf('G+ start %d | theta: %.6f | cost: %.6e\n', s, theta, cost);
            end

            Gdemod = function_heuristic.demComp2SIDHM(theta_best, holo_FT);
            Gplus_demod = Gdemod(:,:,1);
            fprintf('G+  BEST | theta: %.6f | cost: %.6e\n', theta_best, cost_best);
        end

        % ==========================================
        % minimaztion_heuriticII  (G-)
        % Usage:
        %   [Gminus, theta_best, cost_best] = minimaztion_heuriticII(app, holo_FT, fx_max, fy_max);
        %   [Gminus, theta_best, cost_best] = minimaztion_heuriticII(app, holo_FT, fx_max, fy_max, prevTheta);
        % ==========================================
        function [Gminus_demod, theta_best, cost_best] = minimaztion_heuriticII(app, holo_FT, fx_max, fy_max, prevTheta)

            if nargin < 6, prevTheta = []; end
            holo_FT = im2double(holo_FT);

            % --- bounds: full on first run; narrowed on repeat ---
            if ~isempty(prevTheta) && isfinite(prevTheta)
                delta = 10*pi/180; % ±10 degrees
                lb = max(0,     prevTheta - delta);
                ub = min(2*pi,  prevTheta + delta);
            else
                lb = 0;  ub = 2*pi;
            end

            baseOpts = optimoptions('particleswarm', ...
                'Display','off', ...
                'SwarmSize', 4, ...
                'MaxIterations', 100, ...
                'MaxStallIterations', 50, ...
                'FunctionTolerance', 1e-6);

            nStarts = 3;
            theta_best = NaN; cost_best = inf;

            costfun = @(param) function_heuristic.costFunction_SIDHMII(param, holo_FT, fx_max, fy_max);

            for s = 1:nStarts
                if ~isempty(prevTheta) && isfinite(prevTheta)
                    seed = prevTheta;
                else
                    seed = lb + (ub-lb).*rand(1,1);
                end

                initSwarm = repmat(seed, baseOpts.SwarmSize, 1) + (pi/180)*randn(baseOpts.SwarmSize,1);
                initSwarm = max(lb, min(ub, initSwarm));

                opts = optimoptions(baseOpts, 'InitialSwarmMatrix', initSwarm);

                [theta, cost] = particleswarm(costfun, 1, lb, ub, opts);

                if cost < cost_best
                    cost_best  = cost;
                    theta_best = theta;
                end
                fprintf('G- start %d | theta: %.6f | cost: %.6e\n', s, theta, cost);
            end

            Gdemod = function_heuristic.demComp2SIDHM(theta_best, holo_FT);
            Gminus_demod = Gdemod(:,:,2);
            fprintf('G-  BEST | theta: %.6f | cost: %.6e\n', theta_best, cost_best);
        end

        function gplus1 = compensation(app, Gplus_demod,dxy, lambda, factor)
            gplus = fftshift(ifft2(fftshift(Gplus_demod)));
            gplus = gplus * factor;
            [ref_gplus] = function_heuristic.phase_rec(gplus, dxy, dxy, lambda);
            gplus1 = gplus .* ref_gplus;
        end


        function Ft_norm = normalization_FT(app, Given_FT)
            Ft_Gminus = Given_FT;
            Ft_norm = (Ft_Gminus - min(min(Ft_Gminus)))/(max(max(Ft_Gminus)) - min(min(Ft_Gminus)));
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        function [D] = demComp2SIDHM(app, theta, H)

            [X, Y, no] = size(H); % number of training examples

            % Initialize the output array 'D' with zeros.
            D = zeros(X,Y,no);

            M = 1/2*[exp(1i*theta(1)) exp(-1i*theta(1));exp(1i*theta(2)) exp(-1i*theta(2))];
        
            Minv = pinv(M);
            
            D(:,:,1) = Minv(1,1).*H(:,:,1) + Minv(1,2).*H(:,:,2);
            D(:,:,2) = Minv(2,1).*H(:,:,1) + Minv(2,2).*H(:,:,2);

        end


        function  [ref] = Sphase_rec(app,filename, dx, dy, lambda, circ, save)

            % Main function to perform phase reconstruction
            % uses the last third of Ophase_rec
            holo = filename;

            % Get the size of the hologram
            [N,M] = size(holo);
            % Create a meshgrid for the hologram
            [m,n] = meshgrid(-M/2:M/2-1,-N/2:N/2-1);

            % Calculate the Fourier Transform of the hologram and shift the zero-frequency component to the center
            ft_holo = fftshift(fft2(fftshift(holo)));

            % Initialize a filter with zeros
            filter = zeros(N,M);
            % Create a filter mask for the desired region
            filter(2:round(N/2-(N*0.18)),2:M) = 1; % 1nd quadrant

            % Apply the filter to the Fourier Transform of the hologram
            ft_filtered_holo = ft_holo .* filter;

            Circ_filtered_spectrum1 = ft_filtered_holo.*circ;

            [~,idx] = max(Circ_filtered_spectrum1(:));
            % Get the maximum values of fx and fy
            [fy_max,fx_max] = ind2sub([N,M],idx);

            % Find the maximum value in the filtered spectrum
            % Define wavenumber
            k = 2 * pi / lambda;
            % Calculate the center frequencies for fx and fy
            fx_0 = M/2;
            fy_0 = N/2;

            % Calculate the angles for the compensation wave
            theta_x = asin((fx_0 - fx_max +1) * lambda / (M * dx));
            theta_y = asin((fy_0 - fy_max +1) * lambda / (N * dy));
            % Calculate the reference wave
            ref = exp(1i * k * (sin(theta_x) * m * dx + sin(theta_y) * n * dy));
            if save == 1 % copy into flow (phase sum)
                fol = string(datetime("today","Format","uuuu-MM-dd"));
                met = strcat("dx:",string(dx),"\n","dy: ",string(dy),"\n","region: ",string(region),"\n","lambda: ",string(lambda));
                mkdir(fol);
                phaseName = strcat(fol,"\",name,"_OI_DHM_output",ext);
                tN = strcat(name,"_Params.txt");
                % make txt with params 
                writelines(met,tN,"WriteMode","overwrite");
                imwrite(phase,phaseName);
            end
        end

        
        function Ft_norm = normalization_FT_SI(app,Given_FT)
            %same as other normalization func 
            Ft_Gminus = (((Given_FT)));
            Ft_norm = (Ft_Gminus - min(min(Ft_Gminus)))/(max(max(Ft_Gminus)) - min(min(Ft_Gminus)));
        end
        
        function response = createFigurePopup(app, img, mode)
            % createFigurePopup creates a modal dialog box with a figure, text, and Yes/No buttons.
            %   response = createFigurePopup() returns 'Yes' if the Yes button is pressed,
            %   'No' if the No button is pressed, and 'Closed' if the window is closed.

            % Create the main dialog figure
            % 'Name' sets the window title.
            % 'NumberTitle', 'off' removes the figure number from the title.
            % 'MenuBar', 'none' hides the menu bar.
            % 'ToolBar', 'none' hides the toolbar.
            % 'Resize', 'off' prevents resizing.
            % 'WindowStyle', 'modal' makes it a modal dialog, blocking other MATLAB windows.
            % 'CloseRequestFcn' specifies a callback when the user tries to close the window.
            f = figure('Name', 'Minimization Confirmation', ...
                'NumberTitle', 'off', ...
                'MenuBar', 'none', ...
                'ToolBar', 'none', ...
                'Resize', 'off', ...
                'WindowStyle', 'modal', ...
                'Position', [400 300 450 450]); % [left bottom width height]

            % Initialize the response variable
            response = 'Closed';

            % --- Create a sample figure/plot to display ---
            % Create an axes object within the dialog figure to hold the plot.
            % 'Units', 'normalized' makes positioning relative to the parent figure size.
            % 'Position' defines [left bottom width height] relative to the figure.
            ax = axes('Parent', f, ...
                'Units', 'normalized', ...
                'Position', [0.1 0.35 0.8 0.6]); % Adjust position to leave space for text/buttons

            % Plot some sample data
            t = strcat('Minimization Result'," ", mode);
            imagesc(ax,log(abs(img).^2))
            title(ax, t);
            xlabel(ax, '');
            ylabel(ax, '');
            ax.Visible = "off";
            ax.Title.Visible ="on";

            % --- Add the question text ---
            % Create a text UI control.
            % 'Style', 'text' makes it a static text label.
            % 'String' is the text to display.
            % 'HorizontalAlignment', 'center' centers the text.
            % 'FontSize' sets the font size.
            uicontrol('Parent', f, ...
                'Style', 'text', ...
                'String', 'Do you want to repeat the minimization?', ...
                'Position', [50 130 350 30], ... % [left bottom width height]
                'HorizontalAlignment', 'center', ...
                'FontSize', 12);

            % --- Add the 'Yes' button ---
            % 'Style', 'pushbutton' creates a clickable button.
            % 'Callback' specifies the function to execute when the button is clicked.
            % The callback function closes the figure and sets the response.
            uicontrol('Parent', f, ...
                'Style', 'pushbutton', ...
                'String', 'Yes', ...
                'Position', [100 80 100 40], ...
                'FontSize', 12, ...
                'Callback', @(~,~) setResponseAndClose('Yes'));

            % --- Add the 'No' button ---
            uicontrol('Parent', f, ...
                'Style', 'pushbutton', ...
                'String', 'No', ...
                'Position', [250 80 100 40], ...
                'FontSize', 12, ...
                'Callback', @(~,~) setResponseAndClose('No'));

            % --- Set the CloseRequestFcn for the figure ---
            % This function is called if the user tries to close the window using the 'X' button.
            % It ensures the response is 'Closed' before closing.
            set(f, 'CloseRequestFcn', @(~,~) setResponseAndClose('Closed'));

            % --- Wait for the dialog to close ---
            % uiwait makes the current script pause until the figure 'f' is closed.
            uiwait(f);

            % --- Nested function to set response and close figure ---
            % This function is accessible by the main function's variables (f, response).
            function setResponseAndClose(userResponse)
                response = userResponse; % Set the response based on button clicked or close action
                if ishandle(f) % Check if the figure still exists before deleting
                    delete(f); % Close the figure
                end
            end

        end
        
    end

    methods (Access = public)

        function results = holoSort(app,scr, event)

            if str2num(scr.Tag) == 0
                return;
            end
            
             % Get the selected group number from the source control's Value.
            group = scr.Value;

            % Create a copy of the current GROUPS data for temporary use.
            oldG = app.GROUPS;

            % Get the file path of the image corresponding to the UI control's Tag.
            imagePath = string(app.IMAGES(str2num(scr.Tag)));
            
           % Get the file path of the image corresponding to the UI control's Tag.
            im = double(imread(imagePath));
            % --- Section to remove the image from its original group ---
            
            % Find the linear index of the image within the GROUPS cell array.
            % This searches for an identical image matrix.
            index = find(cellfun(@(x) isequal(x, im), app.GROUPS));

            % Check if the image was found.
            if ~isempty(index)
                % Convert the linear index to row and column indices.
                numRows = size(app.GROUPS, 1);
                r = mod(index - 1, numRows) + 1;
                c = floor((index - 1) / numRows) + 1;
                
                if c < size(app.GROUPS, 2)
                    app.GROUPS(r, c:end-1) = app.GROUPS(r, c+1:end);
                end
                app.GROUPS{r, c} = oldG{r, c};
                % Decrement the counter for the number of saved items in the old group.
                app.SAVED(r) = app.SAVED(r) - 1;
                disp(app.GROUPS)
                
            end

             % --- Section to add the image to the new group ---
            
            % Find the first empty column in the selected new group row.
            idx_new_col = find(cellfun('isempty', app.GROUPS(group, :)), 1);

            % If an empty slot is available...
            if ~isempty(idx_new_col)
                % Place the image into the new group.
                app.GROUPS{group, idx_new_col} = im;
                app.SAVED(group) = app.SAVED(group) + 1;
                disp(app.GROUPS)
            end

            results = [];

        end
     
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %launches welcome menus so mode can be selected
            app.IS_WORKING = true; % proves that the code works prefectly
            app.STATE = "Welcome";
            app.MODE = "Welcome";
            app.setVis(app.TabGroup,"off");
            app.ObliqueIlluminationButton.ButtonPushedFcn = @(src, event) StateSwitch(app,src,event);
            app.StructuredIlluminationButton.ButtonPushedFcn = @(src, event) StateSwitch(app,src,event);
            %delete(app.Tab)
            
        end

        % Button pushed function: OIPhaseReconstructionButton
        function OIPhaseReconstructionButtonPushed(app, event)
             % This block of code orchestrates the phase filtering and reconstruction
            % for a single image or a batch of images.
            % It retrieves parameters from the UI, processes each hologram,
            % and prepares the results for super-resolution.

            % Get parameters from the app's 'PERAMS' property.
            % The line below retrieves parameters from UI components, which is
            % a common practice in App Designer apps.
            inny = app.PERAMS; 

            % Create a vector 'boxes' with values from UI edit fields and dropdown.
            % This is for single-image processing.
            boxes = [app.PixelSizeXumEditField.Value,app.PixelSizeYumEditField.Value,app.WavelengthumEditField.Value,app.RegionDropDown.Value - 48];

            % Store the pixel sizes in the app's DXY property.
            app.DXY = [app.PixelSizeXumEditField.Value,app.PixelSizeYumEditField.Value];
            
            % Check if there is more than one image to process.
            if app.TOTAL_IMG > 1
                 % Get the size of the first image to pre-allocate arrays.
                [n,m] = size(imread(string(app.IMAGES(1))));

                % Pre-allocate cell arrays and matrices for storing results.
                app.COMPLEX_FEILD = cell(1, app.TOTAL_IMG);
                app.PHASES = cell(1, app.TOTAL_IMG);
                app.FILTERS = cell(1, app.TOTAL_IMG);
                app.CIRCS = cell(1, app.TOTAL_IMG);
                app.SUMC = zeros(n,m);
                app.SUMF = zeros(n,m);

                % Loop through each image.
                for i = 1:app.TOTAL_IMG
                   
                    % Get the current image file path.
                    im = string(app.IMAGES(i));
                    %disp(im)

                    % Conditional logic to choose parameters: either from a table ('inny')
                    % or from the UI controls ('boxes').
                    if app.getVal(app.samPerams) == 0 && sum(inny(i,:)) ~= 0
                         % Use parameters from the table if they are valid.
                        [filt, circ,complexFeild, rad, phase] = app.phaseFilter(im, inny(i,1),inny(i,2),inny(i,3),inny(i,4), app.SaveBox.Value);
                    elseif sum(boxes) == 0
                         % If the parameters are zero, display an error and exit.
                        msgbox("ERROR: bad parameters","non-modal");
                        return
                    else
                        % Use parameters from the UI controls.
                        [filt, circ,complexFeild, rad, phase] = app.phaseFilter(im, boxes(1),boxes(2),boxes(3),boxes(4),app.SaveBox.Value);
                    end
                    % Store the results in the pre-allocated cell arrays.
                    app.COMPLEX_FEILD{i} = complexFeild;
                    app.PHASES{i} = (phase);
                    app.FILTERS{i} = filt;
                    app.CIRCS{i} = circ;

                    % Store the initial radius of the first filter.
                    if i == 1
                        app.CircFinal = rad;
                    end

                    % Accumulate the filtered holograms and circular filters.
                    app.SUMF = filt + app.SUMF;
                    app.SUMC = circ + app.SUMC;
                   

                    disp("done")
                end
                % Set up the axes for plotting the phase image.
                app.setAxis(app.PhaseAx,"off","on","Phase",'gray');

                 % Display the phase of the last processed image in the UI.
                imagesc( app.PHASES{i}, 'Parent', app.PhaseAx);
                cb = colorbar(app.PhaseAx, "eastoutside");
                cb.Label.String = "rad";
                imagesc( phase, 'Parent', app.PhaseAx);
                
            else
                 % This block handles the case of a single image.
                % Get the single image file path.
                im = string(app.IMAGES);

                % Call phaseFilter with parameters from the 'boxes' vector.
                [filt, circ,complexFeild, rad, phase] = app.phaseFilter(im, boxes(1),boxes(2),boxes(3),boxes(4), app.SaveBox.Value);

                % Store the results directly in app properties (not cell arrays).
                app.COMPLEX_FEILD = complexFeild;
                app.PHASES = phase;
                app.PHASES = phase;
                app.FILTERS = filt;
                app.CIRCS = circ;
                app.CircFinal = rad;

                % Set up the axes and display the phase image.
                app.setAxis(app.PhaseAx,"off","on","phase",'gray');
                cb = colorbar(app.PhaseAx, "eastoutside");
                cb.Label.String = "rad";
                imagesc( phase, 'Parent', app.PhaseAx);
            end
            % Update the app's state to indicate the phase reconstruction is complete.
            app.STATE = "PHASE";

            % Make the 'FTPSwitch' and 'ConjugateCheckBox' UI components visible.
            app.setVis(app.FTPSwitch, "on");
            app.setVis(app.ConjugateCheckBox, "on");

             % Display a message box to the user to guide them to the next step.
             msgbox("Please proceed to the 'SR Phase' tab","non-modal")


        end

        % Button pushed function: LoadhologramsButton
        function LoadhologramsButtonPushed(app, event)
            % LoadhologramsButtonPushed Callback function for the Load Holograms button.
            %   This function allows the user to select one or more hologram files
            %   (with the '.bmp' extension), loads them, and initializes the app's
            %   state and UI for the next steps.
            %
            %   Inputs:
            %   - app: The main app object, which holds all UI components and properties.
            %   - event: The event data (not used directly here).

            % Open a file dialog to allow the user to select one or more files.
            % The "MultiSelect","on" option allows multiple file selection.
            % The "*.bmp" filter shows only bitmap files.
            [fName, fPath]= uigetfile("MultiSelect","on","*.bmp");
           
            % Concatenate the file path and file name(s) to get the full file path.
            % This ensures the app can find the files regardless of the current working directory.
            FILE = strcat(fPath, fName);

            % Check if the user cancelled the file selection.
            if isempty(fName)
                % If no files were selected, show an error message and return.
                msgbox("ERROR: no Files","non-modal")
                return;
            
            % Check if the user selected multiple files. The class of 'fName' will be 'cell'
            % if more than one file is selected.    
            elseif class(FILE(2)) == "cell"
                % Store the file paths in the app's IMAGES property.
                app.IMAGES = FILE;
                % Set the total number of images.
                app.TOTAL_IMG = size(FILE, 2);
                % Initialize a parameter matrix for each image.
                app.PERAMS = zeros(app.TOTAL_IMG, 4);

                % Initialize a counter for saved parameters for each image.
                app.SAVED = zeros(1, app.TOTAL_IMG);

                % Set the current image index to 1.
                app.CURRENT_IMG = 1;

                % Read the first image to get its dimensions.
                img = imread(string(app.IMAGES(1)));
                [x,y] = size(img);

                % Set the axis limits for the two main axes (amplitude and phase).
                app.setAxisLim(app.AmpAx, x, y);
                app.setAxisLim(app.PhaseAx, x, y);

                % Display the original hologram in the Amplitude Axis.
                imagesc( img, 'Parent', app.AmpAx);

                % Compute and display the Fourier Transform of the hologram in the Phase Axis.
                % fftshift() is used to center the zero-frequency component.
                ftI = fftshift(fft2(fftshift(img)));
                imagesc(log10(abs(ftI)), 'Parent', app.PhaseAx);

                % Set the properties of the Phase Axis for the FT display.
                app.setAxis(app.PhaseAx,'off','on','Fourier transform','default');

                % Add a colorbar to the Phase Axis.
                colorbar(app.PhaseAx);

                % Draw a region of interest rectangle on the Phase Axis.
                app.regionRec(app.PhaseAx, app.dRegion)

                % Make the main axes and controls for navigating images visible.
                app.setVis([app.AmpAx, app.PL, app.PR], "on")
                axis(app.AmpAx, 'off')

                 % Get the colorbar handle and set its label.
                cb = colorbar(app.PhaseAx);
                cb.Label.String = "a.u";

                % Update the text field with the name of the first file.
                app.tFile.Value = string(FILE(1));

                app.setVis([app.AmpAx, app.PL, app.PR], "on");
                axis(app.AmpAx, 'off')
                
            else 
                % This block handles the case of a single file being selected.
                % Store the single file path.
                app.IMAGES = string(FILE);
                 % Set the total number of images to 1.
                app.TOTAL_IMG = 1;

                % Initialize the parameter matrix for a single image.
                app.PERAMS = zeros(app.TOTAL_IMG, 4);
                
                % Initialize the saved counter.
                app.SAVED = zeros(1, app.TOTAL_IMG);
                app.CURRENT_IMG = 1;

                % Read the single image.
                img = imread(string(app.IMAGES));
                [x,y] = size(img);

                % Set axis limits.
                app.setAxisLim(app.AmpAx, x, y);
                app.setAxisLim(app.PhaseAx, x, y);

                % Display the original hologram in the Amplitude Axis.
                imagesc(img, 'Parent', app.AmpAx);
                ftI = fftshift(fft2(fftshift(img)));
                imagesc(log10(abs(ftI)), 'Parent', app.PhaseAx);
                app.setAxis(app.PhaseAx,'off','on','Fourier transform','default');
                cb = colorbar(app.PhaseAx);
                app.regionRec(app.PhaseAx, 1)
                app.setVis([app.AmpAx, app.PL, app.PR], "on")
                axis(app.AmpAx, 'off');
                app.tFile.Value = string(FILE);
                axis(app.AmpAx, 'off');
                cb.Label.String = "a.u";

            end
            app.STATE = "loaded";
            
        end

        % Button pushed function: SRQPIMapButton
        function SRQPIMapButtonPushed(app, event)
            % SRQPIMapButtonPushed Callback function for the "SR QPI Map" button.
            %   This function initiates the super-resolution phase reconstruction process.
            %   It takes the pre-processed and summed holograms, calls the core
            %   `phaseSum` function to generate a super-resolved map, and then
            %   updates the app's UI with the results and relevant statistics.
            %
            %   Inputs:
            %   - app: The main app object, which contains all UI components and properties.
            %   - event: The event data from the button push (not used directly here).

            % Get the summed filtered holograms (SUMF) and summed circular filters (SUMC)
            % from the app's properties. These are the inputs for the super-resolution algorithm.
            sf = app.SUMF;
            sc = app.SUMC;

            % Use a conditional statement to select the correct wavelength parameter
            % based on the app's current mode. si is structured illuminutuion  
            if lower(app.MODE) == "si"
                [map, thic, phase, ratio] = app.phaseSum(sf,sc, app.NS.Value, app.NIM.Value, app.WavelengthumEditField_S.Value,app.SRSave.Value);
            else
                [map, thic, phase, ratio] = app.phaseSum(sf,sc, app.NS.Value, app.NIM.Value, app.WavelengthumEditField.Value,app.SRSave.Value);
            end
            
            % Store the generated thickness and phase maps in the app's properties
            % so they can be accessed by other functions later.
            app.MaskThic = thic;
            app.MaskPhase = phase;
           
            % Display the super-resolved power spectrum ('map') in the `threshAx` UI axes.
            imagesc(map, "Parent", app.threshAx);

            % Store the super-resolved map in the app's properties.
            app.MAP = map;
            [x,y] = size(map);
            app.setAxisLim(app.threshAx, x,y);
            axis(app.threshAx,"off");
            app.threshAx.Title.String = "Super-resolved Power spectrum";
            colormap(app.threshAx, "gray");
            norm0 = app.phaseNorm(app.CircFinal);

            %figure, imagesc(abs(mBW)), colormap gray


            St = strcat("The lateral resolution has been improved by a factor of ", string(ratio));
            app.Stats.Text = string(St);
            app.setVis(app.InformationPanel, "on");

        end

        % Button pushed function: PR, gN
        function previewNext(app, event)
            
            P = event.Source;
            disp(P)
            if P == (app.gN)
                
                region = app.RegionDropDown_S.Value - 48;
                boxes = [app.PixelSizeXumEditField_S.Value,app.PixelSizeYumEditField_S.Value,app.WavelengthumEditField_S.Value, region];
                parent = app.groupPrev;
                app.CURRENT_IMG(2) = app.CURRENT_IMG(2) + 1;
                if app.CURRENT_IMG(2) > app.GROUP_LIM
                   app.CURRENT_IMG(2) = 1;
                   app.CURRENT_IMG(1) = app.CURRENT_IMG(1) + 1;
                end
                if app.CURRENT_IMG(1) > app.NUM_GROUPS
                    app.CURRENT_IMG(1) = 1;
                    app.CURRENT_IMG(2) = 1;
                end
                app.setAxis(parent,'off','off',"Hologram","gray");
                disp(app.CURRENT_IMG)
                im = app.GROUPS{app.CURRENT_IMG};
                imagesc(im,"Parent",parent);
                app.setAxis(parent,'off','on',"Hologram","gray");
                app.ftSwitch.Value = "Hologram";
                colorbar(parent,"off");
            else
                region = app.RegionDropDown.Value - 48;
                boxes = [app.PixelSizeXumEditField.Value,app.PixelSizeYumEditField.Value,app.WavelengthumEditField.Value, region];
                parent = app.AmpAx;
                cur = app.CURRENT_IMG + 1;

                saved = zeros(1,app.TOTAL_IMG);
                prev = cur - 1;
                disp("test")
                disp(saved)

                if cur > app.TOTAL_IMG
                    cur = 1;
                else
                    app.CURRENT_IMG = cur;
                    disp(app.CURRENT_IMG)
                    img = imread(string(app.IMAGES(app.CURRENT_IMG)));
                    app.setVis(app.AmpAx,"off");
                    imagesc(img, 'Parent', app.AmpAx);
                    app.setVis(app.AmpAx,"on");
                    app.setVis(app.PhaseAx,"off");
                    axis(app.AmpAx, 'off')
                    if app.FTPSwitch.Visible == "on" && app.FTPSwitch.Value == "Phase"
                        imagesc(app.PHASES{app.CURRENT_IMG},'Parent', app.PhaseAx);
                        app.setAxis(app.PhaseAx,'off','on',"Phase","gray");
                    else
                        ftI = fftshift(fft2(fftshift(img)));
                        imagesc(log10(abs(ftI)), 'Parent', app.PhaseAx);
                        app.regionRec(app.PhaseAx, (app.RegionDropDown.Value - 48));
                        app.setAxis(app.PhaseAx,'off','on','Fourier transform','default');
                    end
                    app.tFile.Value = string(app.IMAGES(app.CURRENT_IMG));
                end

                checkSum = sum((app.PERAMS(prev,:) == boxes));

                disp (checkSum)

                if checkSum ~= 4 && saved(prev) == 0
                    app.PERAMS(prev,:)= [app.PixelSizeXumEditField.Value app.PixelSizeYumEditField.Value app.WavelengthumEditField.Value region];
                    app.SAVED(prev) = 1;
                    disp(app.PERAMS)
                    disp(saved)
                end
                if app.getVal(app.samPerams) == 0
                    if app.SAVED(app.CURRENT_IMG) == 1
                        app.PixelSizeXumEditField.Value = app.PERAMS(app.CURRENT_IMG, 1);
                        app.PixelSizeYumEditField.Value = app.PERAMS(app.CURRENT_IMG, 2);
                        app.WavelengthumEditField.Value = app.PERAMS(app.CURRENT_IMG, 3);
                        app.RegionDropDown.Value = app.PERAMS(app.CURRENT_IMG, 4);
                    else
                        app.PixelSizeXumEditField.Value = 0;
                        app.PixelSizeYumEditField.Value = 0;
                        app.WavelengthumEditField.Value = 0;
                        app.RegionDropDown.Value = 1;
                    end
                end
            end


        end

        % Button pushed function: PL, gP
        function previewBack(app, event)
            P = event.Source;
            disp(P)
            if P == (app.gP)

                region = app.RegionDropDown_S.Value - 48;
                boxes = [app.PixelSizeXumEditField_S.Value,app.PixelSizeYumEditField_S.Value,app.WavelengthumEditField_S.Value, region];
                parent = app.groupPrev;
                app.CURRENT_IMG(2) = app.CURRENT_IMG(2) - 1;
                if app.CURRENT_IMG(2) < 1
                    app.CURRENT_IMG(2) = 2;
                    app.CURRENT_IMG(1) = app.CURRENT_IMG(1) - 1;
                end
                if app.CURRENT_IMG(1) < 1
                    app.CURRENT_IMG(1) = 1;
                    app.CURRENT_IMG(2) = 1;
                end
                app.setAxis(parent,'off','off',"Hologram","gray");
                disp(app.CURRENT_IMG)
                im = app.GROUPS{app.CURRENT_IMG};
                imagesc(im,"Parent",parent);
                app.setAxis(parent,'off','on',"Hologram","gray");
                app.ftSwitch.Value = "Hologram";
                colorbar(parent,"off");
            else
                cur = app.CURRENT_IMG - 1;
                region = app.RegionDropDown.Value - 48;
                boxes = [app.PixelSizeXumEditField.Value,app.PixelSizeYumEditField.Value,app.WavelengthumEditField.Value, region];
                saved = zeros(1,app.TOTAL_IMG);
                prev = cur + 1;
                disp("test")
                disp(saved)
                if cur < 1
                    cur = 1;
                else
                    app.CURRENT_IMG = cur;
                    disp(app.CURRENT_IMG)
                    img = imread(string(app.IMAGES(app.CURRENT_IMG)));
                    app.setVis(app.AmpAx,"off");
                    imagesc(img, 'Parent', app.AmpAx);
                    app.setVis(app.AmpAx,"on");
                    app.setVis(app.PhaseAx,"off");
                    axis(app.AmpAx, 'off')
                    if app.FTPSwitch.Visible == "on" && app.FTPSwitch.Value == "Phase"
                        imagesc(app.PHASES{app.CURRENT_IMG},'Parent', app.PhaseAx);
                        app.setAxis(app.PhaseAx,'off','on',"Phase","gray");
                    else
                        ftI = fftshift(fft2(fftshift(img)));
                        imagesc(log10(abs(ftI)), 'Parent', app.PhaseAx);
                        app.regionRec(app.PhaseAx, (app.RegionDropDown.Value - 48));
                        app.setAxis(app.PhaseAx,'off','on','Fourier transform','default');
                    end
                    app.tFile.Value = string(app.IMAGES(app.CURRENT_IMG));
                end
                checkSum = sum((app.PERAMS(prev,:) == boxes));

                if checkSum ~= 4 && saved(prev) == 0
                    app.PERAMS(prev,:)= [app.PixelSizeXumEditField.Value app.PixelSizeYumEditField.Value app.WavelengthumEditField.Value region];
                    app.SAVED(prev) = 1;
                    disp(app.PERAMS)
                    disp(saved)
                end
                if app.getVal(app.samPerams) == 0
                    if app.SAVED(app.CURRENT_IMG) == 1
                        app.PixelSizeXumEditField.Value = app.PERAMS(app.CURRENT_IMG, 1);
                        app.PixelSizeYumEditField.Value = app.PERAMS(app.CURRENT_IMG, 2);
                        app.WavelengthumEditField.Value = app.PERAMS(app.CURRENT_IMG, 3);
                        app.RegionDropDown.Value = app.PERAMS(app.CURRENT_IMG, 4);
                    else
                        app.PixelSizeXumEditField.Value = 0;
                        app.PixelSizeYumEditField.Value = 0;
                        app.WavelengthumEditField.Value = 0;
                        app.RegionDropDown.Value = 1;
                    end
                end
            end

            % end
        end

        % Value changed function: RegionDropDown, RegionDropDown_S
        function RegionDropDownValueChanged(app, event)
            P = event.Source;
            disp(P)
            if P == (app.RegionDropDown_S)
                value = app.RegionDropDown_S.Value - 48;
                parent = app.groupPrev;
                a = 1;
                if app.ftSwitch.Value == "Hologram" && app.ftSwitch.Visible == "on"
                    return
                elseif app.STATE == "init"
                    app.dRegion = value;
                else
                    while a == 1
                        img = app.GROUPS{app.CURRENT_IMG};
                        ftI = fftshift(fft2(fftshift(img)));
                        app.setVis(parent,"off");
                        imagesc(log10(abs(ftI)), 'Parent', parent);
                        app.regionRec(parent, value);
                        app.setVis(parent,"on");
                        axis(parent, 'off')
                        a = 0;
                    end

                end
            else
                value = app.RegionDropDown.Value - 48;
                %val2 = app.RegionDropDown_S.Value - 48;
                a = 1;
                if app.FTPSwitch.Value == "Phase" && app.FTPSwitch.Visible == "on"
                    return
                elseif app.STATE == "init"
                    app.dRegion = value;
                else
                    while a == 1
                        if app.TOTAL_IMG > 1
                            img = imread(string(app.IMAGES(app.CURRENT_IMG)));
                        else
                            img = imread(string(app.IMAGES));
                        end
                        ftI = fftshift(fft2(fftshift(img)));
                        app.setVis(app.PhaseAx,"off");
                        imagesc(log10(abs(ftI)), 'Parent', app.PhaseAx);
                        app.regionRec(app.PhaseAx, value);
                        app.setVis(app.PhaseAx,"on");
                        axis(app.PhaseAx, 'off')
                        a = 0;
                    end

                end
            end
        end

        % Value changed function: FTPSwitch, ftSwitch
        function FTPSwitchValueChanged(app, event)
            P = event.Source;
            disp(P)
            if P == (app.ftSwitch)
                parent = app.groupPrev;
                value = app.ftSwitch.Value;
                im = app.GROUPS{app.CURRENT_IMG};
                switch value
                    case "Hologram"
                        app.setAxis(parent,'off','off',"Hologram","gray");
                        disp(app.CURRENT_IMG)
                        imagesc(im,"Parent",parent);
                        app.setAxis(parent,'off','on',"Hologram","gray");
                        colorbar(parent, "off");
                    case "FT"
                        ftI = fftshift(fft2(fftshift(im)));
                        app.setAxis(parent,'off','off',"Fourier Transform","default");
                        imagesc(log10(abs(ftI)), 'Parent', parent);
                        app.regionRec(parent,(app.RegionDropDown_S.Value - 48));
                        app.setAxis(parent,'off','off',"Fourier Transform","default");
                        cb = colorbar(parent, 'eastoutside');
                        cb.Label.String = 'a.u';
                end
            else
                value = app.FTPSwitch.Value;

                if app.TOTAL_IMG > 1
                    cur = imread(string(app.IMAGES(app.CURRENT_IMG)));
                    ph = app.PHASES{app.CURRENT_IMG};
                else
                    cur = imread(string(app.IMAGES));
                    ph = app.PHASES;
                end

                switch value
                    case "FT"
                        app.PhaseAx.Visible = 'off';
                        ftI = fftshift(fft2(fftshift(cur)));
                        imagesc(log10(abs(ftI)), 'Parent', app.PhaseAx);
                        app.regionRec(app.PhaseAx,(app.RegionDropDown.Value - 48));
                        app.setAxis(app.PhaseAx, "off", "on", "Fourier Transform", "default");
                        app.setVis(app.ConjugateCheckBox, "off");
                        cb = colorbar(app.PhaseAx, 'eastoutside');
                        cb.Label.String = 'a.u';
                        axis(app.PhaseAx, "off");

                    case "Phase"
                        app.PhaseAx.Visible = 'off';
                        imagesc(ph, 'Parent', app.PhaseAx);
                        app.setAxis(app.PhaseAx, "off", "on", "Phase", "gray");
                        app.setVis(app.ConjugateCheckBox, "on");
                        b = colorbar(app.PhaseAx, 'eastoutside');
                        b.Label.String = 'rad';
                        axis(app.PhaseAx, "off");
                end
            end
        end

        % Value changed function: ConjugateCheckBox
        function ConjugateCheckBoxValueChanged(app, event)
            value = app.ConjugateCheckBox.Value;
            if app.TOTAL_IMG > 1
                c = app.COMPLEX_FEILD{app.CURRENT_IMG};
            else
                c = app.COMPLEX_FEILD;
            end

            if value == 1
                disp(value)
                p = angle(conj(c));
                app.PhaseAx.Visible = 'off';
                imagesc(p, 'Parent', app.PhaseAx);
                app.setAxis(app.PhaseAx, "off", "on", "Phase", "gray");
            else
                disp(value)
                p = angle(c);
                app.PhaseAx.Visible = 'off';
                imagesc(p, 'Parent', app.PhaseAx);
                app.setAxis(app.PhaseAx, "off", "on", "Phase", "gray");
            end


        end

        % Value changed function: ThickSwitch
        function ThickSwitchValueChanged(app, event)
            value = app.ThickSwitch.Value;
            [x,y] = size(app.MaskThic);
             prefil = improfile(app.MaskThic, x,y);
                
             if value == "Phase map"
                 
                 if app.ThicknessCheckBox.Value == 1
                     app.setAxis(app.threshAx,'off','off',"0", "gray");
                     colorbar(app.threshAx, 'off');
                     imagesc(app.MaskThic, "Parent", app.threshAx);
                     app.setAxis(app.threshAx,'off','on',"0", "gray");
                     cb = colorbar(app.threshAx, 'southoutside');
                     cb.Label.String = 'Thickness (um)';
                 else
                     app.setAxis(app.threshAx,'off','off',"0", "gray");
                     colorbar(app.threshAx, 'off');
                     imagesc(app.MaskPhase, "Parent", app.threshAx);
                     
                     app.setAxis(app.threshAx,'off','on',"0", "gray");
                     cb = colorbar(app.threshAx, 'southoutside');
                     cb.Label.String = 'Phase (rad)';
                 end
             else
                 imagesc(app.MAP, "Parent", app.threshAx);
                 colorbar(app.threshAx,'off');
                 app.setAxis(app.threshAx,'off','on',"0", "gray");

             end


                %plot of the profiles
                %figure(6)
                
            
        end

        % Button pushed function: StructLoad
        function StructLoadButtonPushed(app, event)
            fig = app.gSelect;
            disp(event.Source.Tag)
            initY = 330;
            step = 30;
            app.NUM_GROUPS = app.getVal(app.groupNUM);
            app.SAVED = zeros(1,app.NUM_GROUPS);
            IMG = 0;
            
            if app.NUM_GROUPS < 1
                msgbox("ERROR: No groups","modal")
                return
            end
            
            % Create the options for a dropdown menu to select a group.
            % The first option is a placeholder, and the rest are "Group 1", "Group 2", etc.
            opt = strings(1,app.NUM_GROUPS);
            val = 0:app.NUM_GROUPS;
            opt(1) = "Pick group"; 
            for i = 2:app.NUM_GROUPS+1
                opt(i) = strcat("Group ",string(i-1));
            end

            [f,p] = uigetfile("*.bmp","Select holograms",MultiSelect="on");

            holos = strcat(p,f);

            if size(holos,2) >= app.NUM_GROUPS && isfile(holos(app.NUM_GROUPS))
                IMG = length(holos);
                app.GROUP_LIM = round(length(holos)/app.NUM_GROUPS);
                app.IMAGES = strcat(p,f);
                app.TOTAL_IMG = IMG;
                app.GROUPS = cell(app.NUM_GROUPS,app.GROUP_LIM);
                app.ORDER = cell(app.NUM_GROUPS*app.GROUP_LIM);
                app.CURRENT_IMG = zeros(1,2);
                app.CURRENT_IMG = [1,1];
                disp(class(app.GROUPS))
                disp(size(app.GROUPS))

            else
                msgbox("ERROR: No Images selected","modal")
                return
            end
           
            for j = 1:IMG
                app.DROPDOWN{i} = uidropdown(fig,"Position",[140 initY 80 25],"Items", opt,"ItemsData",val,"Tag",string(j),"ValueChangedFcn",@app.holoSort);%uicontrol(fig,Style="popupmenu");
                %e = uidropdown(fig,"Items",opt);
                L(i) = uicontrol(fig,Style= "text");
                %c.Position = [80 initY 80 25];
                L(i).Position = [20 (initY + 5) 120 15];
                L(i).String = f(j);
                L(i).Tooltip = f(j);
                %c.String = opt;
                %set(c,"Callback",@app.holoSort);
                initY = initY - step;
            end
            app.setVis([app.gPreveiw, app.StructRun, app.ParametersPanel_S], "on")
            app.STATE = "load";

        end

        % Button pushed function: gPreveiw
        function gPreveiwButtonPushed(app, event)
            im = app.GROUPS{1,1};
            if isempty(im)
                msgbox("Please pair all holograms")
                return;
            end
            app.CURRENT_IMG = [1,1];
            [x,y] = size(im);
            app.setAxisLim(app.groupPrev,x,y);
            app.setAxis(app.groupPrev,'off','on',"Hologram", "gray");
            imagesc(app.groupPrev,im)
            app.gPreveiw.Text = "Refresh";
            app.gN.Visible = "on";
            app.gP.Visible = "on";
            app.ftSwitch.Visible = "on";
            
            
        end

        % Button pushed function: StructRun
        function StructRunButtonPushed(app, event)

            app.DXY = [app.PixelSizeXumEditField_S.Value, app.PixelSizeYumEditField_S.Value ];
            im = (app.GROUPS{1,1});
          
            [N,M] = size(im);
            [n,m] = meshgrid(-M/2:M/2-1,-N/2:N/2-1);

            filter = zeros(N,M);
            app.SUMC = zeros(N,M);
            app.SUMF = zeros(N,M);
            region = app.RegionDropDown_S.Value - 48;

            if region== 1
                filter(50:round(N/2-(N*0.19)),round(M/2+(M*0.18)):M-50) = 1; % 1nd quadrant
            elseif region== 2
                filter(1:round(N/2-(N*0.185)),1:round(M/2-(M*0.185))) = 1;  % 2nd quadrant
            elseif region== 3
                filter(round(N/2+(N*0.15)):N-40,40:round(M/2-(M*0.18))) = 1; % 3nd quadrant
            else
                filter(round(N/2+(N*0.075)):N-500,round(M/2+(M*0.07)):M-600) = 1; % 4nd quadrant
            end

            %
            [g, h] = size(app.GROUPS);
            for i = 1:g
                for j = 1:h

                    ims  = app.GROUPS{i,j};%loop
                    ft = fftshift(fft2(fftshift(ims)));
                    filterFt = ft.*filter;
                    ftBin{i,j} = filterFt;

                    th = app.getThresh(filterFt);

                    [circ, rad] = app.circ_Struc(filterFt, th,N);
                    if i == 1
                        app.CircFinal = rad;
                        app.THRESH = th;
                    end
                    ftc = filterFt.*circ;

                end
            end

            orde = cell(app.NUM_GROUPS, 4);

            % Loop through the groups to find the peak locations of the holograms.
            % The loop increments by 2, which suggests a paired-hologram approach
            % (e.g., phase-shifted pairs).
            for i = 1:2:(app.NUM_GROUPS*app.GROUP_LIM)
                [Cmax1, Rmax1, Cmax2 , Rmax2] = app.max_holo(ftBin{i});
                if ~isempty(Cmax1)
                    r = 1;
                    if i > 1 && i <= 3
                        r = i-1;
                    elseif i > 3
                        r = i-2;
                    else
                        r = i;
                    end
                    orde{r,1} = Cmax1;
                    orde{r,2} = Rmax1;
                    orde{r,3} = Cmax2;
                    orde{r,4} = Rmax2;
                    disp("o")
                end
            end

            % Initialize variables for the main demodulation loop.
            % row = 1;
            AllGplusdemod = zeros(N,M,g);
            AllGminusdemod = zeros(N,M,g);

            for row = 1:g
                repeat_minimization = true;

                % Store best thetas for narrowing on repeat
                theta1_prev = [];
                theta2_prev = [];

                while repeat_minimization
                    % --- Load this pair ---
                    holo_FT(:,:,1) = ftBin{row,1};
                    holo_FT(:,:,2) = ftBin{row,2};

                    % --- Recompute maxima for THIS pair now ---
                    [Cmax1, Rmax1, Cmax2, Rmax2] = app.max_holo(holo_FT(:,:,1));
                    fy_max = [Cmax1, Cmax2];   % columns (x)
                    fx_max = [Rmax1, Rmax2];   % rows (y)

                    % ==================== PLUS ====================
                    [Gplus_demod, theta1, cost1] = app.minimaztion_heuritic( ...
                        holo_FT, fx_max, fy_max, theta1_prev);

                    % save result for this row
                    AllGplusdemod(:,:,row) = Gplus_demod;

                    % ask user about PLUS, act immediately on the answer
                    ans_plus = string(app.createFigurePopup(Gplus_demod, "Plus"));
                    if strcmpi(ans_plus, "yes")
                        theta1_prev = theta1;     % narrow using last best theta
                        close all
                        continue                   % rerun loop with updated theta1_prev
                    end

                    % ==================== MINUS ====================
                    [Gminus_demod, theta2, cost2] = app.minimaztion_heuriticII( ...
                        holo_FT, fx_max, fy_max, theta2_prev);

                    % save result for this row
                    AllGminusdemod(:,:,row) = Gminus_demod;

                    % ask user about MINUS, act immediately on the answer
                    ans_minus = string(app.createFigurePopup(Gminus_demod, "Minus"));
                    if strcmpi(ans_minus, "yes")
                        theta2_prev = theta2;     % narrow using last best theta
                        close all
                        continue                   % rerun loop with updated theta2_prev
                    end

                    % If neither PLUS nor MINUS requested a repeat, we’re done
                    repeat_minimization = false;
                    close all
                end
            end


            for count=1:g
                disp(count)

                TempGplus = AllGplusdemod(:,:,count);
                TempGminus= AllGminusdemod(:,:,count);

                factor = -1;
                gplus = app.compensation(TempGplus, app.DXY(1), app.WavelengthumEditField_S.Value, factor);
                factor = 1;
                gminus = app.compensation(TempGminus, app.DXY(1), app.WavelengthumEditField_S.Value, factor);

                Gplus = FT(gplus);
                Gminus = FT(gminus);
                %figure(100), imagesc(abs(Gminus).^.2), title 'power spectrum Not notmalized'

                Ft_norm_Gplus = app.normalization_FT_SI(Gplus);
                Ft_norm_Gminus = app.normalization_FT_SI(Gminus);

                absFTp_pow = abs(Ft_norm_Gplus).^0.2;          % abs -> ^0.2
                absFTm_pow = abs(Ft_norm_Gminus).^0.2;

                %figure(10), imagesc(absFTp_pow), title 'power spectrum after norm'

                
                TH = 0.107;                            % same as New_SI

                BWp = imbinarize(absFTp_pow, TH);    % binarize
                BWm = imbinarize(absFTm_pow, TH);

                %figure, imagesc(BWp)

                % Use your existing circle finder on the binarized maps
                % NOTE: pass the complex FT as the "CF0" input so the returned filter keeps phase
                [FTp_masked, circ_p, rad_p] = app.findcircle(BWp, Ft_norm_Gplus);
                [FTm_masked, circ_m, rad_m] = app.findcircle(BWm, Ft_norm_Gminus);
                app.RADII = rad_m;
                % Save binary masks (for weighting)
                app.SUMC = app.SUMC + circ_p + circ_m;
                % Save for summation (complex, masked)
                app.SUMF = app.SUMF + FTp_masked + FTm_masked;

                
            end


           msgbox("Please proceed to the 'SR Phase' tab","non-modal");


        end

        % Value changed function: ThicknessCheckBox
        function ThicknessCheckBoxValueChanged(app, event)
            value = app.ThicknessCheckBox.Value;
            if value == 1
                app.setAxis(app.threshAx,'off','off',"0", "gray");
                colorbar(app.threshAx, 'off');
                imagesc(app.MaskThic, "Parent", app.threshAx);
                app.setAxis(app.threshAx,'off','on',"0", "gray");
                cb = colorbar(app.threshAx, 'southoutside');
                cb.Label.String = 'Thickness (um)';
            else
                app.setAxis(app.threshAx,'off','off',"0", "gray");
                colorbar(app.threshAx, 'off');
                imagesc(app.MaskPhase, "Parent", app.threshAx);

                app.setAxis(app.threshAx,'off','on',"0", "gray");
                cb = colorbar(app.threshAx, 'southoutside');
                cb.Label.String = 'Phase (rad)';
            end
        
            
        end

        % Value changed function: saveThick
        function saveThickValueChanged(app, event)
            value = app.saveThick.Value;
            if value == 1 && app.SRSave.Value ~= 1
                app.SRSave.Value = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.OpenSRQPIUIFigure = uifigure('Visible', 'off');
            app.OpenSRQPIUIFigure.Name = 'OpenSRQPI';
            app.OpenSRQPIUIFigure.Icon = fullfile(pathToMLAPP, 'smalllogo_OIRL_nobackground.png');
            app.TabGroup = uitabgroup(app.OpenSRQPIUIFigure);
            app.ObliqueilluminationpreparationTab = uitab(app.TabGroup);
            app.ObliqueilluminationpreparationTab.Title = 'Oblique illumination preparation';
            app.PhaseAx = uiaxes(app.ObliqueilluminationpreparationTab);
            title(app.PhaseAx, 'Phase')
            xlabel(app.PhaseAx, 'X')
            ylabel(app.PhaseAx, 'Y')
            zlabel(app.PhaseAx, 'Z')
            colormap(app.PhaseAx, 'gray')
            app.PhaseAx.Visible = 'off';
            app.AmpAx = uiaxes(app.ObliqueilluminationpreparationTab);
            title(app.AmpAx, 'Hologram')
            xlabel(app.AmpAx, 'X')
            ylabel(app.AmpAx, 'Y')
            zlabel(app.AmpAx, 'Z')
            colormap(app.AmpAx, 'gray')
            app.AmpAx.Visible = 'off';
            app.OIPhaseReconstructionButton = uibutton(app.ObliqueilluminationpreparationTab, 'push');
            app.OIPhaseReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @OIPhaseReconstructionButtonPushed, true);
            app.OIPhaseReconstructionButton.Text = 'OI Phase Reconstruction';
            app.LoadhologramsButton = uibutton(app.ObliqueilluminationpreparationTab, 'push');
            app.LoadhologramsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadhologramsButtonPushed, true);
            app.LoadhologramsButton.Text = 'Load holograms';
            app.SaveBox = uicheckbox(app.ObliqueilluminationpreparationTab);
            app.SaveBox.Text = 'Save Phase maps';
            app.tFile = uieditfield(app.ObliqueilluminationpreparationTab, 'text');
            app.tFile.Editable = 'off';
            app.ParametersPanel = uipanel(app.ObliqueilluminationpreparationTab);
            app.ParametersPanel.Title = 'Parameters:';
            app.samPerams = uicheckbox(app.ParametersPanel);
            app.samPerams.Visible = 'off';
            app.samPerams.Enable = 'off';
            app.samPerams.Text = 'Same parameters';
            app.samPerams.Value = true;
            app.FTRegion1DropDownLabel = uilabel(app.ParametersPanel);
            app.FTRegion1DropDownLabel.Text = 'FT Region +1';
            app.RegionDropDown = uidropdown(app.ParametersPanel);
            app.RegionDropDown.Items = {'1', '2', '3', '4'};
            app.RegionDropDown.ItemsData = {'1', '2', '3', '4'};
            app.RegionDropDown.ValueChangedFcn = createCallbackFcn(app, @RegionDropDownValueChanged, true);
            app.RegionDropDown.Value = '1';
            app.PixelSizeYumEditField = uieditfield(app.ObliqueilluminationpreparationTab, 'numeric');
            app.PixelSizeXumEditFieldLabel = uilabel(app.ObliqueilluminationpreparationTab);
            app.PixelSizeXumEditFieldLabel.Text = 'Pixel Size X (um)';
            app.PixelSizeXumEditField = uieditfield(app.ObliqueilluminationpreparationTab, 'numeric');
            app.PixelSizeYumEditFieldLabel = uilabel(app.ObliqueilluminationpreparationTab);
            app.PixelSizeYumEditFieldLabel.Text = 'Pixel Size Y (um)';
            app.WavelengthumEditFieldLabel = uilabel(app.ObliqueilluminationpreparationTab);
            app.WavelengthumEditFieldLabel.Text = 'Wavelength (um)';
            app.WavelengthumEditField = uieditfield(app.ObliqueilluminationpreparationTab, 'numeric');
            app.PR = uibutton(app.ObliqueilluminationpreparationTab, 'push');
            app.PR.ButtonPushedFcn = createCallbackFcn(app, @previewNext, true);
            app.PR.Visible = 'off';
            app.PR.Text = '>';
            app.PL = uibutton(app.ObliqueilluminationpreparationTab, 'push');
            app.PL.ButtonPushedFcn = createCallbackFcn(app, @previewBack, true);
            app.PL.Visible = 'off';
            app.PL.Text = '<';
            app.Label = uilabel(app.ObliqueilluminationpreparationTab);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.FTPSwitch = uiswitch(app.ObliqueilluminationpreparationTab, 'slider');
            app.FTPSwitch.Items = {'FT', 'Phase'};
            app.FTPSwitch.ValueChangedFcn = createCallbackFcn(app, @FTPSwitchValueChanged, true);
            app.FTPSwitch.Visible = 'off';
            app.FTPSwitch.Tooltip = {'Change preveiw between the Fourier transform (FT) and the Phase map (Phase) of the current image'};
            app.FTPSwitch.Value = 'Phase';
            app.RegionEditField = uieditfield(app.ObliqueilluminationpreparationTab, 'numeric');
            app.RegionEditField.Editable = 'off';
            app.RegionEditField.Visible = 'off';
            app.RegionEditFieldLabel = uilabel(app.ObliqueilluminationpreparationTab);
            app.RegionEditFieldLabel.Visible = 'off';
            app.RegionEditFieldLabel.Text = 'Region';
            app.ConjugateCheckBox = uicheckbox(app.ObliqueilluminationpreparationTab);
            app.ConjugateCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConjugateCheckBoxValueChanged, true);
            app.ConjugateCheckBox.Visible = 'off';
            app.ConjugateCheckBox.Text = 'Conjugate';
            app.StructuredIlluminaionpreparationTab = uitab(app.TabGroup);
            app.StructuredIlluminaionpreparationTab.Title = 'Structured Illuminaion preparation';
            app.groupPrev = uiaxes(app.StructuredIlluminaionpreparationTab);
            title(app.groupPrev, 'Hologram')
            xlabel(app.groupPrev, 'X')
            ylabel(app.groupPrev, 'Y')
            zlabel(app.groupPrev, 'Z')
            app.groupPrev.Visible = 'off';
            app.StructLoad = uibutton(app.StructuredIlluminaionpreparationTab, 'push');
            app.StructLoad.ButtonPushedFcn = createCallbackFcn(app, @StructLoadButtonPushed, true);
            app.StructLoad.Text = 'Load holograms';
            app.NumberofdifferntanglesLabel = uilabel(app.StructuredIlluminaionpreparationTab);
            app.NumberofdifferntanglesLabel.Text = 'Number of different angles';
            app.groupNUM = uieditfield(app.StructuredIlluminaionpreparationTab, 'numeric');
            app.gSelect = uipanel(app.StructuredIlluminaionpreparationTab);
            app.gSelect.Title = 'Hologram pairing';
            app.ftSwitch = uiswitch(app.StructuredIlluminaionpreparationTab, 'slider');
            app.ftSwitch.Items = {'Hologram', 'FT'};
            app.ftSwitch.ValueChangedFcn = createCallbackFcn(app, @FTPSwitchValueChanged, true);
            app.ftSwitch.Visible = 'off';
            app.ftSwitch.Value = 'Hologram';
            app.gP = uibutton(app.StructuredIlluminaionpreparationTab, 'push');
            app.gP.ButtonPushedFcn = createCallbackFcn(app, @previewBack, true);
            app.gP.Visible = 'off';
            app.gP.Text = '<';
            app.gN = uibutton(app.StructuredIlluminaionpreparationTab, 'push');
            app.gN.ButtonPushedFcn = createCallbackFcn(app, @previewNext, true);
            app.gN.Visible = 'off';
            app.gN.Text = '>';
            app.gPreveiw = uibutton(app.StructuredIlluminaionpreparationTab, 'push');
            app.gPreveiw.ButtonPushedFcn = createCallbackFcn(app, @gPreveiwButtonPushed, true);
            app.gPreveiw.Visible = 'off';
            app.gPreveiw.Text = 'Preview';
            app.ParametersPanel_S = uipanel(app.StructuredIlluminaionpreparationTab);
            app.ParametersPanel_S.Title = 'Parameters:';
            app.ParametersPanel_S.Visible = 'off';
            app.samPerams_2 = uicheckbox(app.ParametersPanel_S);
            app.samPerams_2.Visible = 'off';
            app.samPerams_2.Enable = 'off';
            app.samPerams_2.Text = 'Same parameters';
            app.samPerams_2.Value = true;
            app.PixelSizeYumEditField_2Label = uilabel(app.ParametersPanel_S);
            app.PixelSizeYumEditField_2Label.Text = 'Pixel Size Y (um)';
            app.PixelSizeYumEditField_S = uieditfield(app.ParametersPanel_S, 'numeric');
            app.PixelSizeXumEditField_2Label = uilabel(app.ParametersPanel_S);
            app.PixelSizeXumEditField_2Label.Text = 'Pixel Size X (um)';
            app.PixelSizeXumEditField_S = uieditfield(app.ParametersPanel_S, 'numeric');
            app.WavelengthumEditField_2Label = uilabel(app.ParametersPanel_S);
            app.WavelengthumEditField_2Label.Text = 'Wavelength (um)';
            app.WavelengthumEditField_S = uieditfield(app.ParametersPanel_S, 'numeric');
            app.FTRegion1Label = uilabel(app.ParametersPanel_S);
            app.FTRegion1Label.Text = 'FT Region +1';
            app.RegionDropDown_S = uidropdown(app.ParametersPanel_S);
            app.RegionDropDown_S.Items = {'1', '2', '3', '4'};
            app.RegionDropDown_S.ItemsData = {'1', '2', '3', '4'};
            app.RegionDropDown_S.ValueChangedFcn = createCallbackFcn(app, @RegionDropDownValueChanged, true);
            app.RegionDropDown_S.Value = '1';
            app.StructRun = uibutton(app.StructuredIlluminaionpreparationTab, 'push');
            app.StructRun.ButtonPushedFcn = createCallbackFcn(app, @StructRunButtonPushed, true);
            app.StructRun.Visible = 'off';
            app.StructRun.Text = 'SI Phase Reconstruction';
            app.SRPhaseTab = uitab(app.TabGroup);
            app.SRPhaseTab.Title = 'SR Phase';
            app.threshAx = uiaxes(app.SRPhaseTab);
            title(app.threshAx, 'Super-resolved Power spectrum')
            colormap(app.threshAx, 'gray')
            app.threshAx.Visible = 'off';
            app.Paramiters2 = uipanel(app.SRPhaseTab);
            app.Paramiters2.Title = 'Parameters:';
            app.SRQPIMapButton = uibutton(app.Paramiters2, 'push');
            app.SRQPIMapButton.ButtonPushedFcn = createCallbackFcn(app, @SRQPIMapButtonPushed, true);
            app.SRQPIMapButton.Text = 'SR QPI Map';
            app.RefractiveIndexSamplen_sLabel = uilabel(app.Paramiters2);
            app.RefractiveIndexSamplen_sLabel.Text = 'Refractive Index Sample (ns):';
            app.NS = uieditfield(app.Paramiters2, 'numeric');
            app.RefactiveIndexMediumnimLabel = uilabel(app.Paramiters2);
            app.RefactiveIndexMediumnimLabel.Text = 'Refactive Index Medium (nim):';
            app.NIM = uieditfield(app.Paramiters2, 'numeric');
            app.SRSave = uicheckbox(app.Paramiters2);
            app.SRSave.Text = 'Save FT + Phase';
            app.saveThick = uicheckbox(app.Paramiters2);
            app.saveThick.ValueChangedFcn = createCallbackFcn(app, @saveThickValueChanged, true);
            app.saveThick.Text = 'Save thickness';
            app.Image_2 = uiimage(app.SRPhaseTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'logo_OIRL_nobackground.png');
            app.InformationPanel = uipanel(app.SRPhaseTab);
            app.InformationPanel.Title = 'Information';
            app.InformationPanel.Visible = 'off';
            app.ThickSwitch = uiswitch(app.InformationPanel, 'slider');
            app.ThickSwitch.Items = {'Fourier Transform', 'Phase map'};
            app.ThickSwitch.ValueChangedFcn = createCallbackFcn(app, @ThickSwitchValueChanged, true);
            app.ThickSwitch.Value = 'Fourier Transform';
            app.Stats = uilabel(app.InformationPanel);
            app.ThicknessCheckBox = uicheckbox(app.InformationPanel);
            app.ThicknessCheckBox.ValueChangedFcn = createCallbackFcn(app, @ThicknessCheckBoxValueChanged, true);
            app.ThicknessCheckBox.Text = 'Thickness';
            app.Welcome = uitabgroup(app.OpenSRQPIUIFigure);
            app.UMassDOIRLTab = uitab(app.Welcome);
            app.UMassDOIRLTab.Title = 'UMass D OIRL';
            app.ObliqueIlluminationButton = uibutton(app.UMassDOIRLTab, 'push');
            app.ObliqueIlluminationButton.Text = 'Oblique Illumination';
            app.StructuredIlluminationButton = uibutton(app.UMassDOIRLTab, 'push');
            app.StructuredIlluminationButton.Text = 'Structured Illumination';
            app.WelcomeLabel = uilabel(app.UMassDOIRLTab);
            app.WelcomeLabel.Text = 'Welcome';
            app.Image = uiimage(app.UMassDOIRLTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo_OIRL_nobackground.png');
            % Show the figure after all components are created
            app.OpenSRQPIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SR_QPI_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OpenSRQPIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OpenSRQPIUIFigure)
        end
    end
end